classdef CodigoGraficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIMainWindow                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ERRORESLabel                    matlab.ui.control.Label
        CONTINUARButton                 matlab.ui.control.Button
        GrficastestdeconsistenciacinticaCheckBox  matlab.ui.control.CheckBox
        GrficastestdeconsistenciacinemticaCheckBox  matlab.ui.control.CheckBox
        GrficasdelongitudesmuscularesCheckBox  matlab.ui.control.CheckBox
        GrficasdeElectromiografiaCheckBox  matlab.ui.control.CheckBox
        GrficasdeCinticaCheckBox        matlab.ui.control.CheckBox
        GrficasdecinemticaCheckBox      matlab.ui.control.CheckBox
        NormativadeadultoCheckBox       matlab.ui.control.CheckBox
        SesionEditField                 matlab.ui.control.EditField
        SesionEditFieldLabel            matlab.ui.control.Label
        TomaseleccionadaEditField       matlab.ui.control.EditField
        TomaseleccionadaEditFieldLabel  matlab.ui.control.Label
        REPORTEGRFICASC                 matlab.ui.control.Button
        PlotreportTab                   matlab.ui.container.Tab
        TerminarButton                  matlab.ui.control.Button
        ReporTGenerCons                 matlab.ui.control.Button
        ReporTGenerGraphs               matlab.ui.control.Button
    end

    
    properties (Access = private)
        path                    % Location of the folder
        allfiles                % Saves the folder info
        P_Info                  % Structure that saves the patient's information
        Selec_trial             % Selected trial to be plotted for kinematic and kinetics
        P_Variables             % Structure that saves the Kinematic and kinetic variables for the report
        N_Variables             % Structure that saves the normal Kinematic and kinetic variables for the report
        Walking = struct();     % Structure to save the walkings
        trial=[];               % Vector to save the numer of the walkings
        kinet=0                   %Indictaes if the patients has kinetic variables
        emg                    %Indictaes if the patients has emg variables
        ruta                    % Location of the archive .exe
        Kinet_individual
        %Styles for Tables
        tableStyle
        mainHeaderRowStyle
        mainHeaderTextStyle
        otherTextStyle
        Stab_Data   %Array with Stabilometry data
        Flag_OC     %Indicates if the patient perfomed the test with the Close eye 1=there is OC, 0= there is not OC
        testype     %Indicates the type of test. 1: Estabilom, 2: COP, 3: 6 minutes, 4:RMS and 5: Plot report 6: Consistency
        PageNumber % Number of the page 
        
        planos = ["Sagital", "Frontal", "Transverse"]; % Name of the 3 planes
        lines = ["Angles1","Torque1","Power1","Torque1","Force1"];   % Plots of the kinematic page
      
    end
    
    methods (Access = private)

        %This folder reads and save in global variables the Patient Info
        function Folder_Info(app)
            %% Load the folder information
            app.path = uigetdir('C:\Users\marcha\Desktop');             %Open the desktop folder
            app.allfiles = dir(app.path);                                             %Describes what is inside the folder
            %Get the selected trial
            try
%                 nota = fileread(append(app.path,'\NOTA.txt'));
%                 matchStr = regexp(nota,'\w* \w*','match');
%                 app.Selec_trial= str2num(cell2mat(matchStr(1)));
%                 app.Selec_trial
%                 Session= str2num(cell2mat(matchStr(3)));
%                 app.P_Info.('Selec_trial')=app.Selec_trial;
%                 app.P_Info.('Session')= Session;
% % 
                app.Selec_trial= str2num(app.TomaseleccionadaEditField.Value);
                Session= str2num(app.SesionEditField.Value);
                app.P_Info.('Selec_trial')=app.Selec_trial;
                app.P_Info.('Session')= Session;  
            catch ME
                if (strcmp(ME.identifier,'MATLAB:fileread:cannotOpenFile'))
                    msg = 'Por favor verificar que exista la NOTA';
                    msgbox(msg,'Error en lectura de archivos','warn');
                end
            end

            %Save the Walking trials
            for w=1:3
                num=strrep(app.allfiles(w+5).name,'W','');          % w+5 bc the walking are in the 5,6 and 7th position
                num=strrep(num,'.xml','');
                app.trial(w)=str2num(num);
        
                app.Walking.(append('W',string(app.trial(w))))=readstruct(append(app.path,'/','W',string(app.trial(w)),'.xml'));
            end

            %% Save the patient info
            Data = readstruct(append(app.path,'/','LAB.xml'));
            Data = Data.trial.static.text;

            for w=1:length(Data)
                variable= Data(w);
                var = variable.dataAttribute;
                name =strrep(variable.labelAttribute,' ','');
                app.P_Info.(name)=var;
            end
            age = between(app.P_Info.Birthday,app.P_Info.Sessiondate);
            app.P_Info.age = calyears(age);
        end
       
        %This Function calculates the results for each Stabilom Trial
        function data= decodificar(~,texto)
            data=[];
            salto=0;
            posicion=1;
            texto=strrep(texto,'I ','');
            data_string=split(texto);
            paso=false;
            for i= 1:length(data_string)
                if data_string(i)== "S"
                    salto=str2num(data_string(i+1));
                    paso=true;
                else
                    if paso
                        paso=false;
                        data(posicion:salto)=nan;
                        posicion=salto+1;

                    else
                        data(posicion)=data_string(i);
                        posicion=posicion+1;
                    end
                end
            end
            if length(data)<101
                data=[data,nan(1,101-length(data))];
            end
        end

        function results_Estab = Estab_Variables(app,data)
            time_trials = str2double(app.TStab);               % Trial duration
            %% Rango [mm]
            % Desplazamiento maximo del COP            
            RangoML = abs(max(data(:,1))-min(data(:,1)));

            %% Mean radial displacement Promedio [mm]
            % Mean distance from the COP to the centroid
            RadiX = [];             %Vector with radial displacements in x 
            for i=1:length(data)
                RadiX = [RadiX; abs(mean(data(:,1))-data(i,1))];
            end
            DesRadiML = mean(RadiX);
            DesRadiML = round(DesRadiML,2);

            %% Mean Velocity [mm/s] = Distance/time
            % COP Distance [mm]
            disY = 0;
            for i=1:(length(data)-1)
                disY = disY + abs(data(i+1,2)-data(i,2));
            end

            % Velocity
            Vel = round(disY/time_trials,2);

            %% Area per second [mm^2/s]
            %Triangle's area sum (centroid / i point / i+1)/30 sec
            A_s = 0;                        %Area per second
            for i=1:(length(data)-1)
                Trian = [mean(data(:,1)) mean(data(:,2)) 1; data(i,1) data(i,2) 1; data(i+1,1) data(i+1,2) 1];      %Matriz de vertices de triangulo
                A_s = A_s + abs(det(Trian)/2);
            end
            A_sec = round(A_s/time_trials,2);     
            
            results_Estab = [RangoML,DesRadiML, Vel, A_sec]; 
        end
        
        function resultsTable = PatientInfoTable(app,Rpte)
            
% 
            import mlreportgen.report.*                %Import the libraries
            import mlreportgen.dom.*
            
            test = ["ESTABILOMETRÍA", "TRAYECTORIA COP", "6 MINUTOS CON SESOR","MÁXIMOS Y MÍNIMOS","GRÁFICAS","TEST DE CONSISTENCIA"];
            test_name =["_ESTABILO", "_TrayectoriaCOP", "_6MIN CON SENSOR","_RMS", " ","_TEST DE C"]; 
            P_name= append(app.P_Info.Firstname," ",app.P_Info.Lastname);
            P_session =string(app.P_Info.Session);
            try
     

                if app.PageNumber == 1

                    rpt = Report(append(app.path,'\',append(P_name,P_session,test_name(app.testype))),'pdf');     %Create the file with the patient's name

                    open(rpt)   %Open report

                else
                    rpt = Rpte;
                end
            catch err
                waitbar(0,err.message)
            end


            cLayout = rpt.Layout;           %Define the current Layout

            pm = PageMargins();             %Instance of Page Margins
            pm.Top = "0in";                 %Set margins to 0in
            pm.Header = "0.30in";
            cLayout.PageMargins =pm;        %Add the instance to the Layout  
    

            %Set stykles according to reports
            if app.testype ==3 || app.testype ==5 || app.testype==6
                cLayout.Landscape = false;            %Sets the page to Portrait when Graficas report or 6minutes
                %Table
                app.tableStyle= {HAlign('center'),Width('100%'), Border('none'), ColSep('none'), RowSep('none'),OuterMargin('0pt', '0pt', '0pt', '0pt'),LineSpacing(1.5)};         %Set the table style to add later
                app.mainHeaderRowStyle = {HAlign('left'),VAlign('middle'), InnerMargin('2pt', '2pt', '2pt', '2pt')};        %Set the Style for the Main Header
                app.mainHeaderTextStyle = {HAlign('left'),Bold, OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Cambria'),FontSize("11pt")};    %Set the text Style of the main header of te table
                app.otherTextStyle = {OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Cambria'),FontSize("11pt")};
                pm.Bottom = "0in";
                pm.Footer = "0in";
                pm.Left = "0.85in";
                pm.Right = "0.85in";
            else
                cLayout.Landscape = true;       %Set the page to Landscape 
                %Table
                app.tableStyle= {HAlign('center'),Width('80%'), Border('none'), ColSep('none'), RowSep('none'),OuterMargin('0pt', '0pt', '0pt', '0pt'),LineSpacing(1.5)};         %Set the table style to add later
                app.mainHeaderRowStyle = {HAlign('center'),VAlign('middle'), InnerMargin('2pt', '2pt', '2pt', '2pt')};        %Set the Style for the Main Header
                app.mainHeaderTextStyle = {HAlign('center'),Bold, OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Cambria')};    %Set the text Style of the main header of te table
                app.otherTextStyle = {OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Cambria')};
            end   
 

            %Load the image of the Logo
            im = Image(append(app.ruta,'Logo.png'));
            im.Width = '2.2in';
            im.Height = '0.6in';            

            % Set up the title                      
            tl= TitlePage();
            title = Paragraph('INSTITUTO ROOSEVELT'); % Set the title
            title.Style = {HAlign("center"),FontFamily("Cambria"),FontSize("13pt"),Color("black"),LineSpacing(2),Bold};           %Set the title style
            subtitlep = Paragraph(append('LABORATORIO DE ANÁLISIS DE MOVIMIENTO REPORTE DE ',test(app.testype)));
            subtitlep.Style = {HAlign("center"),FontFamily("Cambria"),FontSize("13pt"),Color("black"),LineSpacing(1),Bold};
            tl.Title = title;
            tl.Subtitle= subtitlep;
            tl.Author = [];
            tl.PubDate =[];             %Delete information from these variables
            add(rpt, tl);               %Add the title to report
            
            % Construct the header
            header = PDFPageHeader();
            append(header, im);

            % Get the report's page layout and set header
            layout = getReportLayout(rpt);
            layout.PageHeaders = header;

            %Adjust the space for the header and top page margin
            layout.PageMargins.Header = '0.4in';
            layout.PageMargins.Top = '0.13in';

%           Add a Space
            space= Text(' ');
            space.Style = {LineSpacing(1)};
            add(rpt, space);          
            
            %HEADER WITH PATIENT INFORMATION
            tble = Table(4);                                 %Creates a table with 4 columns
            tble.Style = [tble.Style app.tableStyle];        %Set the Style to the table      

            %Table First Rows
            r=TableRow;     %Defines the first Table row
            r.Style = [r.Style app.mainHeaderRowStyle];          %Sets the Table Row to the predefined Style
            r = TableInput(app,r,'NOMBRE: ',app.mainHeaderTextStyle,1,2);   
            r = TableInput(app,r,append(app.P_Info.Firstname,' ',app.P_Info.Lastname),app.otherTextStyle,1,1); 
            r = TableInput(app,r,'EDAD: ',app.mainHeaderTextStyle,1,1); 
            r = TableInput(app,r,append(string(app.P_Info.age),' años'),app.otherTextStyle,1,1);
            append(tble,r);

            %Table second Rows
            r2=TableRow;     %Defines the first Table row
            r2.Style = [r2.Style app.mainHeaderRowStyle];          %Sets the Table Row to the predefined Style
            r2 = TableInput(app,r2,'DIAGNÓSTICO: ',app.mainHeaderTextStyle,1,2);
            r2 = TableInput(app,r2,app.P_Info.Pathology,app.otherTextStyle,1,1);
            r2 = TableInput(app,r2,'NÚMERO ID: ',app.mainHeaderTextStyle,1,1);
            r2 = TableInput(app,r2,app.P_Info.Internalcode,app.otherTextStyle,1,1);
            append(tble,r2);              

            if app.testype == 5 && app.PageNumber ==1 %Creates a new row to introduce the number of the file on the report
                r3=TableRow;     %Defines the first Table row
                r3.Style = [r3.Style app.mainHeaderRowStyle];          %Sets the Table Row to the predefined Style
                r3 = TableInput(app,r3,'ARCHIVO: ',app.mainHeaderTextStyle,1,2);
                r3 = TableInput(app,r3,app.Walking.(app.trial(1)).trial.labelAttribute,app.otherTextStyle,1,1);
                r3 = TableInput(app,r3,'FECHA EXAMEN: ',app.mainHeaderTextStyle,1,2);
                r3 = TableInput(app,r3,append(string(app.P_Info.Sessiondate)),app.otherTextStyle,1,1);
                append(tble,r3);  
                add(rpt,tble);
            elseif app.testype == 6 && app.PageNumber ==1 %Creates a new row to introduce the number of the file on the report
                r3=TableRow;     %Defines the first Table row
                r3.Style = [r3.Style app.mainHeaderRowStyle];          %Sets the Table Row to the predefined Style
                r3 = TableInput(app,r3,'FECHA EXAMEN: ',app.mainHeaderTextStyle,1,2);
                r3 = TableInput(app,r3,append(string(app.P_Info.Sessiondate)),app.otherTextStyle,1,1);
                append(tble,r3);  
                add(rpt,tble);
            else
                add(rpt,tble);
            end
                                   
            resultsTable = rpt;            
        end
        
        function loadNormal(app,root,type,n,data_type,vn,Var_names)


            alldata=root.(type); % Saves the struct with the type of the normal data
            if type == "cycle"
                data_type = ["angle","force1d","torque1d","power","emg","track1d","vel1d"];
                Var_names = ["Angles1","Force1","Torque1","Power1","EMG","M_Lengths","M_Veloc","Lengths","Scalar","Velocity","Times","Frequencies","DAngles","FFT",...
                    "Angles2","Angles3","Cadence3","Scalar2","Scalar3","ScalarP","Point1D3","Times3","Velocity3","Torque2","Torque3","Force2","Force3","Power2","Power3"];                
            end

            for p=1:n  % there are only 5 variables in the cycle type
                if Var_names(p+vn)=="EMG" %When the variable is EMG
                    var = alldata.("emg"); %Do not save the band
                else
                    var = alldata.(append(data_type(p),'_band'));        %Save each cycle variable
                end
                info = [];
                for i=1:length(var)
                    variable= var(i);                                %Save each variable
                    if type == "cycle"
                        if Var_names(p+vn)=="EMG"
                            data = decodeEMG(app,variable);
                            Mdata =data;
                            SDdata = data;
                        else
                            data = strrep(variable.dataAttribute,'S 0 ','');  %Delete the S O at the beginin
                            data = str2num(data)';
                            if length(data) < 202
                                data = [data;zeros(202-length(data),1)];
                            end
                            data = data/variable.scaleFactorAttribute;
                            Mdata= array2table(data(1:2:end));
                            SDdata= array2table(data(2:2:end));
                        end
                    else
                        data = (str2num(variable.dataAttribute))/variable.scaleFactorAttribute;
                        Mdata = array2table(data(1));
                        SDdata = array2table(data(2));
                    end
                    Mdata.Properties.VariableNames = append(variable.descriptionAttribute,'.M');
                    SDdata.Properties.VariableNames = append(variable.descriptionAttribute,'.S');
                    info = [info,Mdata,SDdata];             %Add each variable to the table
                end
                app.N_Variables.(Var_names(p+vn)) = info;
            end            
        end

        function loadData(app,type,data_type,Var_names,vn)
        
         
            for w=1:3
                Walk = readstruct(append(app.path,'\',app.trial(w),'.xml'));
                alldata = Walk.trial.(type);
                fn = string(fieldnames(alldata));           %To get tthe filed names
        
                if type == "cycle" 
                    app.trial(w)
           
                    if isempty(find(fn=="force1d", 1))
                    else 
                        if w==1
                            app.Kinet_individual =1;
                        end                        
                        app.kinet=app.kinet+1;
                        
                        disp(app.kinet)
            
                    end                                                            
    
                                                   
                end
                lp = length(data_type);    

                for p=1:lp
                    try
                        if type == "stream"
                            for m=1:length(alldata)
                                var = alldata(m).(data_type(p));
                            end

                           
                        else
                            var = alldata.(data_type(p));
                            if data_type(p) == "emg"
                                app.emg = 1;                                
                            end
                        end

                        Info = [];
                        for i=1:length(var)
                            variable= var(i);
                            if type == "cycle"
                                data=decodificar(app,variable.dataAttribute)';
                               
                            else
                                if class(variable.dataAttribute)== "double"
                                    data =variable.dataAttribute;
                                else
                                    if type == "stream"
                                        data = strrep(variable.dataAttribute,'S','');
                                        data = str2num(data)';
                                    else
                                        data = strrep(variable.dataAttribute,'I','');
                                        data = str2num(data);
                                        if length(data)>1
                                            data= data(1);
                                        end
                                    end
                                end
                            end


                            data= array2table(data/variable.scaleFactorAttribute);
                            data.Properties.VariableNames=variable.labelAttribute;
                           
                            Info=[Info,data];
                        end
                        app.P_Variables.(app.trial(w)).(Var_names(p+vn))=Info;
                    catch err
                        if data_type(p) == "emg"
                            app.emg = 0;
                                                     
                        end
                        app.trial(w)

                        disp(err.message)


                    end
                end
            end       
            
        end
        
        function results = Plot_KinmatGraps(~,plane,n,data,page,Rto,Lto,plotname,label1,label2)     
            
            if page ==1             %Plot kinematic data
                % Setup of the figure
                if plane == "Sagital" 
                    f = 1;
                elseif plane == "Frontal" 
                    f=2;
                else plane = "Transverse"; 
                    f=3;              
                end
              

                if ~ishandle(f)  % Verificar si la figura ya existe
                    a = figure(f);  % Crear la figura sin abrirla
                else
                    set(0, 'CurrentFigure', f);  % Establecer la figura actual sin abrirla
                    a =gcf ;
                end

                a.Visible="off";


                set(gcf,'Position',[80,20,300,1200]);       %Sets the figure position
                set(gcf,'color','w');                       %Sets the figure color to white

                hax=subplot(5,1,n);                         %Divides the figure into 5 subplots and indictaes tha n is the subplot to be edited
                hax.Visible='off';
                a.Name = plane;
                Yname = 'Ángulo (deg)';
                colors2 =[0 0.6 0 ; 0.6 0 0];


            elseif page ==2
                if ~ishandle(plane+3)  % Verificar si la figura ya existe
                    a = figure(plane+3);  % Crear la figura sin abrirla
                else
                    set(0, 'CurrentFigure', plane+3);  % Establecer la figura actual sin abrirla
                    a =gcf ;
                end

                a.Visible="off";

                
                set(gcf,'Position',[80,500,1000,200])       %Sets the figure position
                set(gcf,'color','w');                       %Sets the figure color to white
                hax=subplot(1,3,n);                         %Divides the figure into 3 subplots and indictaes tha n is the subplot to be edited
                hax.Visible='off';

                if plane==1
                    Yname = 'Ángulo (deg)';
                elseif plane==2 || plane==4
                    Yname = 'Momento (N*m)';
                elseif plane==3
                    Yname = 'Potencia (W)';
                elseif plane==5
                    Yname = 'Fuerza (N)';
                end                
            end
           
            %Variables
            cicle = data(:,1);            
            M_Right_Normal = data(:,2);
            SD_Right_Normal = data(:,3);
            M_Left_Normal = data(:,4);
            SD_Left_Normal = data(:,5);
            Right_Data = data(:,6);
            Left_Data = data(:,7);    
            RTo = round(Rto);
            LTo = round(Lto);

            % Find the maximum and minimun values of the kinematic plots and the gait
            % Global Max and min values

            % Set the % max to 100


            %Creating the curves with the Mean and SD of the left and right sides
            curve1 = M_Right_Normal+SD_Right_Normal;
            curve2 =M_Right_Normal-SD_Right_Normal;
            curve3 = M_Left_Normal+SD_Left_Normal;
            curve4 =M_Left_Normal-SD_Left_Normal;

            % Creates a vector with the gate cycle from 0 to 100 and then from 100 to 0
            x=[cicle',fliplr(cicle')];

            % Creates the vectors with the curves of the right and left sides
            iBR=[curve1', flip(curve2')];
            iBL=[curve3', flip(curve4')];

            %Plots the mean and the SD as a shaded area of the left and right side
            fill(x,iBR,'k','FaceAlpha',0.2, 'EdgeColor', 'None')
            hold('on')
            plot(cicle,M_Right_Normal,'Color',[0.4 0.4 0.4])
            fill(x,iBL,'k','FaceAlpha',0.2, 'EdgeColor', 'None')   
            hold('on')
            plot(cicle,M_Left_Normal,'Color',[0.4 0.4 0.4])
            
            %plot the Patient Values Left and Rigth

            if page==2 
                if plane~=1
                    Right_Data(RTo+1:end)=nan;
                    Left_Data(LTo+1:end)=nan;
                    plot (cicle, Right_Data,'Color', [0 0.6 0],'LineWidth',1.5);
                    plot(cicle, Left_Data, 'Color',[0.9 0 0], 'LineWidth', 1.5);
                else
                    plot (cicle, Right_Data,'Color', [0 0.6 0],'LineWidth',1.5);
                    plot(cicle, Left_Data, 'Color',[0.9 0 0], 'LineWidth', 1.5);
                end
                
            else
                plot (cicle, Right_Data,'Color', [0 0.6 0],'LineWidth',1.5);
                plot(cicle, Left_Data, 'Color',[0.9 0 0], 'LineWidth', 1.5);                            

            end


            %plot a Zero Line
            zer= zeros(length(cicle));
            plot(cicle, zer,'k--');

            %Find the closer Y limit according to the 5,10, 15 or 20 multiples
            limite= get(hax, 'YLim');
            yaxthi = get(hax, 'YTick'); % Get the Y axis vector
            tc=diff(yaxthi); % Get the difference between the Y axes number to get the width
            tc= tc(1);      % Set the width


            if tc==1 && (((abs(limite(1)-tc))<1)||((abs(limite(2)-tc))<1))
    
                tc=0.5;
            end

     
            if tc == 0.2
                ax_lim=[0.2,0.4,0.6,0.8];    %USE this to set the limits when the thick is 0.2
                add=0.1;                    %Set the numer to add to the limit until find the next addecuate limit
                rnd = 1;                    %Number of Decimals to round to
            elseif tc ==0.5
                ax_lim=[0.5,1.5,2.5,3.5,4.5,5.5,6.5]; %USE this to set the limits when the thick is 0.5
                add=0.1;                             %Set the numer to add to the limit until find the next addecuate limit
                rnd=1;                               %Number of Decimals to round to   
            else
                add =1; %To add to the limit number to set the upper and lower limit
                rnd=0;  %Number of Decimals to round to
            end
            addition = (5*add)/10;
            
            if round(limite(1),2)== floor(limite(1)) 
                limite(1)= limite(1);
            else
                if abs(limite(1))<2 && abs(limite(1))>0.7
                    limite(1)= limite(1);
                else
                    limite(1)= round(limite(1)-addition,1);
                    
                end
                 
            end

            if round(limite(2),2)== floor(limite(2)) 
                limite(2)= limite(2);
            else
                if abs(limite(2))<2&& abs(limite(2))>0.7
                    limite(2)= limite(2);
                else
                   limite(2)= round(limite(2)+addition,1);
                end
                
            end
  
            %Find the upper limit of the plot
            if mod(limite(2),tc)==0                 %To know if the limit is multiple of the plot thick
                                                    %If it is, then set than number as the upper limit
            else
                tu= round(limite(2),rnd);           %If not, then round the numer to the nearest and compare if it is now multiple of the plot thick
                while mod(tu,tc)~=0                 %To know of the rounded limit is multiple of the plot thick
                    tu = tu+add;                    %If not, then add numbers until it is
                    if tc==0.2 || tc==0.5           %If the added number is content on the ax limits then set that as the upper limit
                        if any(ax_lim ==round(tu,rnd))
                            break
                        end
                    end
                end
                
                limite(2)=tu;
            end
            if limite(2)<=0                         %Set the upper limit to the plot thick when this limit is less tan zero
                limite = [limite(1) tc];
                ylim (limite);
            end
            

            %Find the lower limit of the plot
%             if limite(1)<=0 && limite(1) > tc*(-1)

            if limite(1)<=0
                if mod(limite(1),tc)==0
    
                else
                    tl= round(limite(1),rnd);
                    while mod(tl,tc)~=0
                        tl=tl-add;
%                         tl=round(tl,rnd);
                        if tc==0.2 || tc==0.5
                            if any((-1*ax_lim) ==round(tl,rnd))
                                break
                            end
                        end
                    end
                    limite(1)=tl;
                end

            end


            if(limite(1)>= 0)                   %Set the lower limit to -thick when the limit is higher than zero
                limite = [-tc limite(2)];
                ylim (limite);
            end
            

            %Plot the Right and Left Toe Off
            line([RTo RTo], limite,'Color',[0 0.6 0]);
            line([LTo LTo], limite,'Color',[0.9 0 0]);

            % set the plot limits according to the maximun and minimun limit of the
            % plots
            ylim (limite);
            yticks(limite(1):tc:limite(2));
             
            subtitle(plotname,'FontWeight','bold','FontName','Serif','Fontsize',11)
            %Set the X and Y labels
            xlabel('Ciclo [%]','FontName','Serif');
            ylabel(Yname,'FontName','Serif');
            limitey= get(hax,'YLim');

            %Set the upper and lower labels
            text(92-length(char(label2)),limitey(1)+(tc/2),label2,'FontSize',9,'FontName','Serif')
            text(92-length(char(label1)),limitey(2)-(tc/2),label1,'FontSize',9','FontName','Serif')
            
            colors2 =[0 0.6 0 ; 0.6 0 0];


            if page ==1
                joint = ["TRONCO","PELVIS","CADERA","RODILLA"];      %Set the joint titles  
                plotposition = [0.8380,0.6400,0.4420,0.2490,0.0600];    %Vector with the position of the plots so the spacng is enough
                pos = get(hax,'Position');
                pos(4) =0.11800;                %Set the height of each plot
                pos(2)=plotposition(n);         %Set the position of the plot
                set(hax,'Position',pos)
                if plane =="Frontal"
                    title(joint(n),'Fontsize',11,'FontName','Serif')    %Set the joint titles on the frontal column
                end
            elseif page ==2
                if plane ==4 && n==2
                    title("MOMENTOS PLANO FRONTAL",'Fontsize',11,'FontName','Serif')    
                elseif plane==5 && n==2
                    title("FUERZA DE REACCIÓN EN EL PISO",'Fontsize',11,'FontName','Serif') 
                end
                plotposition = [0.0410,0.3500,0.665];    %Vector with the position of the plots so the spacng is enough                
                pos = get(hax,'Position');
                pos(1) =plotposition(n);
                pos(2) = 0.1850;
                pos(3) = 0.2400;
                pos(4) = 0.6160;
                set(hax,'Position',pos)
            end  
                           
            results = a;

            if page == 1 && f==2
                dim=[5,1,5];
                Position=[0.4 0.06 0.2 0.12];
            elseif page==2 && plane==4
                dim=[1,3,3];
                Position=[0.300 0.280 1 0.5000];
            end


            if (page == 1 && f==2) || ( page==2 && plane==4 )
                
                hax=subplot(dim(1),dim(2),dim(3));
                hax.Visible='off';
       
                for pl=1:2
                    plot(rand(1),'color',colors2(pl,:),'LineWidth',2.0)
                    hold on
                end
                hold off
                legend('boxoff')
                leg=legend(' Lado Derecho', ' Lado Izquierdo');


                set(gca,'visible','off')
                leg.FontSize=12;

                leg.ItemTokenSize=[90 ,100];
                leg.LineWidth=0.01;
                leg.FontName = 'Serif';
                leg.Position=Position;

            end


        end
        
        function Param = TableInput(~,row,tx,Styl,col,lspac)

            import mlreportgen.report.*                %Import the libraries
            import mlreportgen.dom.*

            inf = Text(tx);
            inf.Style = [inf.Style Styl];
            te = TableEntry(inf);
            te.Style = {LineSpacing(lspac)};
            te.ColSpan = col;
            append(row,te);

            Param =row;           
        end
        
        function resEMG = PlotEMG(~,f,n,cicle,Remg,Lemg,Nemg,RT,LT,name)                     


            if ~ishandle(f)  % Verificar si la figura ya existe
                a = figure(f);  % Crear la figura sin abrirla
            else
                set(0, 'CurrentFigure', f);  % Establecer la figura actual sin abrirla
                a =gcf ;
            end

             set(gcf,'Position',[80,20,950,1100]);       %Sets the figure position
             set(gcf,'color','w');                       %Sets the figure color to white
             a.Name = 'EMG';                             % Sets the figures name

             hax=subplot(4,3,n);                         %Divides the figure into 5 subplots and indictaes tha n is the subplot to be edited

             %Plot the Right EMG envelope
             plot (cicle,Remg,'Color', [0 0.6 0],'LineWidth',1.5);
             hold on;
             xticks(0:20:100)
             %Plot the normal data
             plot (Nemg,'Color',[0,0,0],'LineWidth',3.5)

             %Plot the Right Toe Off
             limite= get(hax, 'YLim');
             line([RT RT], limite,'Color',[0 0.6 0]);

             subtitle(append(name,' derecho'),'FontWeight','bold','FontName','Serif','Fontsize',12);
             xlabel('Ciclo [%]','FontName','Serif');
             ylabel('EMG [uV]','FontName','Serif');
             ylim([-0.5,limite(2)]);

             %Get position of the subplot
             pos = get(hax,'Position');
             pos(1) = 0.05; %Modify the position of the plot so it is closer to the edge
             set(hax,'Position',pos);

             hax=subplot(4,3,n+1);      %Next subplot

             %Plot the Left EMG envelope
             plot (cicle,Lemg,'Color', [0.9 0 0],'LineWidth',1.5);
             hold on;
             xticks(0:20:100)
             %Plot the normal data
             plot (Nemg,'Color',[0,0,0],'LineWidth',3.5)

             %Plot the Left Toe Off
             limite= get(hax, 'YLim');
             line([LT LT], limite,'Color',[0.9 0 0]);

             subtitle(append(name, ' izquierdo'),'FontWeight','bold','FontName','Serif','Fontsize',12)
             xlabel('Ciclo [%]','FontName','Serif');
             ylabel('EMG [uV]','FontName','Serif');
             ylim([-0.5,limite(2)]);

             %Get position of the subplot
             pos = get(hax,'Position');
             pos(1) = 0.33; %Modify the position of the plot so it is closer to the edge
             set(hax,'Position',pos);

             hax=subplot(4,3,n+2);      %Next subplot

             %Plot the left and right EMG envelopes
             plot (cicle,Remg,'Color', [0 0.6 0],'LineWidth',1.5);
             hold on;
             plot (cicle,Lemg,'Color', [0.9 0 0],'LineWidth',1.5);
             hold on;
             %Set the x width to 20
             xticks(0:20:100)
                
             %Plot the normal data
             plot (Nemg,'Color',[0,0,0],'LineWidth',3.5)

             %Find the closer Y limit according to the 5,10, 15 or 20 multiples
             limite= get(hax, 'YLim');
             yaxthi = get(hax, 'YTick'); % Get the Y axis vector
             tc=diff(yaxthi); % Get the difference between the Y axes number to get the width
             tc= tc(1);       % Set the width

             if tc == 0.2
                 ax_lim=[0.2,0.4,0.6,0.8];    %USE this to set the limits when the thick is 0.2
                 add=0.1;                    %Set the numer to add to the limit until find the next addecuate limit
                 rnd = 1;                    %Number of Decimals to round to
             elseif tc ==0.5
                 ax_lim=[0.5,1.5,2.5,3.5,4.5,5.5,6.5]; %USE this to set the limits when the thick is 0.5
                 add=0.1;                             %Set the numer to add to the limit until find the next addecuate limit
                 rnd=1;                               %Number of Decimals to round to
             else
                 add =1; %To add to the limit number to set the upper and lower limit
                 rnd=0;  %Number of Decimals to round to
             end
            addition = (5*add)/10;
            
            if round(limite(1),2)== floor(limite(1)) 
                limite(1)= limite(1);
            else
                if abs(limite(1))<2 && abs(limite(1))>0.7
                    limite(1)= limite(1);
                else
                    limite(1)= round(limite(1)-addition,1);
                end
 
                 
            end

            if round(limite(2),2)== floor(limite(2)) 
                limite(2)= limite(2);
            else
                if abs(limite(2))<2 && abs(limite(2))>0.7
                    limite(2)= limite(2);
                else
                   limite(2)= round(limite(2)+addition,1);
                end
                
            end


             %Find the upper limit of the plot
             if mod(limite(2),tc)==0                 %To know if the limit is multiple of the plot thick
                 ylimit=limite(2);                   %If it is, then set than number as the upper limit
             else
                 tu= round(limite(2),rnd);           %If not, then round the numer to the nearest and compare if it is now multiple of the plot thick
                 while mod(tu,tc)~=0                 %To know of the rounded limit is multiple of the plot thick
                     tu = tu+add;                    %If not, then add numbers until it is
                     if tc==0.2 || tc==0.5           %If the added number is content on the ax limits then set that as the upper limit
                         if any(ax_lim ==tu)
                             break
                         end
                     end
                 end

                 limite(2)=tu;
             end
             if limite(2)<=0                         %Set the upper limit to the plot thick when this limit is less tan zero
                 limite = [limite(1) tc];
                 ylim (limite);

             end

             %Find the lower limit of the plot
             if limite(1)<=0
                 if mod(limite(1),tc)==0
                     ylimit=limite(1);
                 else
                     tl= round(limite(1),rnd);
                     while mod(tl,tc)~=0
                         tl=tl-add;
                         if tc==0.2 || tc==0.5
                             if any((-1*ax_lim) ==(tl))
                                 break
                             end
                         end
                     end
                     limite(1)=tl;
                 end
             end

             if(limite(1)>= 0)                   %Set the lower limit to -thick when the limit is higher than zero
                 limite = [-tc limite(2)];
                 ylim (limite);
             end


             %Plot the Right and Left Toe Off
             line([RT RT], limite,'Color',[0 0.6 0]);
             line([LT LT], limite,'Color',[0.9 0 0]);

             subtitle(name,'FontWeight','bold','FontName','Serif','Fontsize',11)
             xlabel('Ciclo [%]','FontName','Serif');
             ylabel('EMG [uV]','FontName','Serif');
             ylim([-0.5,limite(2)])

             pos = get(hax,'Position');
             pos(1) =0.61;
             set(hax,'Position',pos)

             resEMG =a; % Returns the figure
        end
        
        function FFT = PlotFFT(~,f,n,cicle,Rfft,Lfft,RMed, LMed,name)

            if ~ishandle(f)  % Verificar si la figura ya existe
                a = figure(f);  % Crear la figura sin abrirla
            else
                set(0, 'CurrentFigure', f);  % Establecer la figura actual sin abrirla
                a =gcf ;
            end
      

            a.Visible="off";
           
            set(gcf,'Position',[80,20,900,1100]);       %Sets the figure position
            set(gcf,'color','w');                       %Sets the figure color to white
            a.Name = 'EMG- Espectros de frecuencia';                             % Sets the figures name

            hax=subplot(4,3,n);                         %Divides the figure into 12 subplots and indictaes tha n is the subplot to be edited
            pos = get(hax,'Position');
            pos(1) = 0.045;
            set(hax,'Position',pos);

            %Plot the Right FFT
            plot (cicle,Rfft,'Color', [0 0.6 0],'LineWidth',1.5);
            hold on;
            xticks(0:120:480)

            subtitle(append(name,' derecho'),'FontWeight','bold','FontName','Serif','Fontsize',12);
            xlabel('Frecuencia [hz]','FontName','Serif');

            h=subplot(4,3,n+1);      %Next subplot

            %right and left Median values
            r = RMed;
            r = num2str(r);
            l = LMed;
            l = num2str(l);

            Derecho = {r};
            Izquierdo = {l};
            T= table(Derecho,Izquierdo); %Creates the table

            %Set the titles for the table
            T.Properties.VariableNames = ["Derecho","Izquierdo"];
            T.Properties.RowNames = "Mediana [Hz]";

            if n ==1
                Post = [0.29 0.820 0.3050 0.04645];
            elseif n == 4
                Post = [0.29 0.600 0.3050 0.04645];
            elseif n ==7
                Post = [0.29 0.390 0.3050 0.04645];
            else
                Post = [0.29 0.180 0.3050 0.04645];
            end


            uitable('Data',T{:,:},'ColumnName',T.Properties.VariableNames,...
                'RowName',T.Properties.RowNames,'Units',h.Units,...
                'ColumnWidth',{70,70},'Position',Post,'FontSize',12,'FontName','Serif');

            h.Visible ='Off';

            hax=subplot(4,3,n+2);      %Next subplot
            pos = get(hax,'Position');
            pos(1) = 0.65;
            set(hax,'Position',pos);

            %Plot the Left FFT
            plot (cicle,Lfft,'Color', [0.9 0 0],'LineWidth',1.5);
            hold on;
            xticks(0:120:480)

            subtitle(append(name,' izquierdo'),'FontWeight','bold','FontName','Serif','Fontsize',12)
            xlabel('Frecuencia [hz]','FontName','Serif');


            FFT =a; % Returns the figure
            
        end
        
        function mLength = Plot_muscle(~,f,n,data,Rto,Lto,plotname,label1,label2)
            % Defines the graphs yto be plotted (f=10 the fisrt forth, f=11 the last
            % sixt)
            if ~ishandle(f)  % Verificar si la figura ya existe
                a = figure(f);  % Crear la figura sin abrirla
            else
                set(0, 'CurrentFigure', f);  % Establecer la figura actual sin abrirla
                a =gcf ;
            end
            set(gcf,'color','w');
            a.Visible ="off";

            cicle = data(:,1);
            RTo = round(Rto);
            LTo = round(Lto);

            if n==1 || n ==3 || n==4 || n==8 % To plot the angles
                if f == 10 || f == 12
                    set(gcf,'Position',[80,80,800,400]);  %Sets the figure position
                    rplot = 2; % Divides the figure into 4 upper plots)
                    cplot = 2;
                    hax=subplot(cplot,rplot,n);

                    %Set the plots position so they fit on the PDF
                    pos = get(hax,'Position');
                    p = [0.190 0.520 0.190 0.520];
                    pos(1) = p(n);
                    pos(3) = 0.250;
                    set(hax,'Position',pos);

                else
                    set(gcf,'Position',[80,80,820,420]);  %Sets the figure position
                    rplot = 3;
                    cplot = 2;
                    if n > 4
                        hax=subplot(cplot,rplot,n-4);  %To get the subplot name
                    else
                        hax=subplot(cplot,rplot,n);
                    end
                    pos = get(hax,'Position');
                    p = [0.070 0.380 0.700 0.070 0.380 0.700];
                    pos(1) = p(n-4);
                    pos(3) = 0.240;
                    set(hax,'Position',pos);
                end
                Yname = 'Ángulo (deg)';
                M_Right_Normal = data(:,2); %Normal data
                SD_Right_Normal = data(:,3);
                M_Left_Normal = data(:,4);
                SD_Left_Normal = data(:,5);
                Right_Data = data(:,6);
                Left_Data = data(:,7);

                %Creating the curves with the Mean and SD of the left and right sides
                curve1 = M_Right_Normal+SD_Right_Normal;
                curve2 =M_Right_Normal-SD_Right_Normal;
                curve3 = M_Left_Normal+SD_Left_Normal;
                curve4 =M_Left_Normal-SD_Left_Normal;

                % Creates a vector with the gate cycle from 0 to 100 and then from 100 to 0
                x=[cicle',fliplr(cicle')];

                % Creates the vectors with the curves of the right and left sides
                iBR=[curve1', flip(curve2')];
                iBL=[curve3', flip(curve4')];

                %Plots the mean and the SD as a shaded area of the left and right side
                fill(x,iBR,'k','FaceAlpha',0.2, 'EdgeColor', 'None')
                hold('on')
                plot(cicle,M_Right_Normal,'Color',[0.4 0.4 0.4])
                fill(x,iBL,'k','FaceAlpha',0.2, 'EdgeColor', 'None')
                hold('on')
                plot(cicle,M_Left_Normal,'Color',[0.4 0.4 0.4])

                %plot a Zero Line
                zer= zeros(length(cicle));
                plot(cicle, zer,'k--');

            else
                if f == 10 || f == 12
                    set(gcf,'Position',[80,80,800,400]);  %Sets the figure position
                    rplot = 2; % Divides the figure into 4 upper plots)
                    cplot = 2;
                    hax=subplot(cplot,rplot,n);

                    %Set the plots position so they fit on the PDF
                    pos = get(hax,'Position');
                    p = [0.190 0.520 0.190 0.520];
                    pos(1) = p(n);
                    pos(3) = 0.250;
                    set(hax,'Position',pos);
                else
                    set(gcf,'Position',[80,80,820,420]);  %Sets the figure position
                    rplot = 3;
                    cplot = 2;
                    if n > 4
                        hax=subplot(cplot,rplot,n-4);  %To get the subplot name
                    else
                        hax=subplot(cplot,rplot,n);
                    end
                    pos = get(hax,'Position');
                    p = [0.070 0.380 0.700 0.070 0.380 0.700];
                    pos(1) = p(n-4);
                    pos(3) = 0.240;
                    set(hax,'Position',pos);
                    if f == 11
                        Yname = 'Longitud anatómica [cm]';
                    elseif f == 13
                        Yname = 'Velocidad[cm/s]';
                        %plot a Zero Line
                        zer= zeros(length(cicle));
                        plot(cicle, zer,'k--');
                        hold on;
                    end

                end
                if f == 10
                    Yname = 'Longitud anatómica [cm]';
                elseif f == 12
                    Yname = 'Velocidad[cm/s]';
                    %plot a Zero Line
                    zer= zeros(length(cicle));
                    plot(cicle, zer,'k--');
                    hold on;
                end                
                Right_Data = data(:,2)*100;
                Left_Data = data(:,3)*100;
            end
            n=n+4;

            % Find the maximum and minimun values of the kinematic plots and the gait
            % Global Max and min values
            [~,rmx]= max(Right_Data);
            [~,lmx]= max(Left_Data);
            [~,rmn]= min(Right_Data);
            [~,lmn]= min(Left_Data);

            % Set the % max to 100
            if rmx >100
                rmx=100;
            end
            if lmx >100
                lmx=100;
            end
            if rmn >100
                rmn=100;
            end
            if lmn >100
                lmn=100;
            end

            %plot the Patient Values Left and Rigth
            plot (cicle, Right_Data,'Color', [0 0.6 0],'LineWidth',1.5);
            hold('on')
            plot(cicle, Left_Data, 'Color',[0.9 0 0], 'LineWidth', 1.5);

            %Find the closer Y limit according to the 5,10, 15 or 20 multiples
            limite= get(hax, 'YLim');
            yaxthi = get(hax, 'YTick'); % Get the Y axis vector
            tc=diff(yaxthi); % Get the difference between the Y axes number to get the width
            tc= round(tc(1),2);      % Set the width

            dif = limite(2)-limite(1); 

            if dif > 0.5 && dif <2 
                limite(2) =  limite(2)+0.5;
                limite(1) = limite(1)-0.5;
            end
            
            if tc == 0.2 && dif <=1
                ax_lim=[0.2,0.4,0.6,0.8];    %USE this to set the limits when the thick is 0.2
                add=0.1;                    %Set the numer to add to the limit until find the next addecuate limit
                rnd = 1;                    %Number of Decimals to round to
            elseif tc == 0.2 && dif >1
                tc = 0.5;
                ax_lim=[0.5,1.5,2.5,3.5,4.5,5.5,6.5]; %USE this to set the limits when the thick is 0.5
                add=0.1;                             %Set the numer to add to the limit until find the next addecuate limit
                rnd=0;                 
            elseif tc ==0.5  
                ax_lim=[0.5,1.5,2.5,3.5,4.5,5.5,6.5]; %USE this to set the limits when the thick is 0.5
                add=0.1;                             %Set the numer to add to the limit until find the next addecuate limit
                rnd=1;                               %Number of Decimals to round to
            else
                add =1; %To add to the limit number to set the upper and lower limit
                rnd=0;  %Number of Decimals to round to
            end
            addition = (5*add)/10;
            
            if round(limite(1),2)== floor(limite(1)) 
                limite(1)= limite(1);
            else
                if abs(limite(1))<2 && abs(limite(1))>0.7
                    limite(1)= limite(1);
                else
                    limite(1)= limite(1)-addition;
                end
 
                 
            end

            if round(limite(2),2)== floor(limite(2)) 
                limite(2)= limite(2);
            else
                if abs(limite(2))<2 && abs(limite(2))>0.7
                    limite(2)= limite(2);
                else
                   limite(2)= limite(2)+addition;
                end
                
            end

            %Find the upper limit of the plot
            l = limite(2);
            if mod(limite(2),tc)==0                 %To know if the limit is multiple of the plot thick
                ylimit=limite(2);                   %If it is, then set than number as the upper limit
            else
                limite(2) = limite(2)+add;
                tu= round(limite(2),rnd);           %If not, then round the numer to the nearest and compare if it is now multiple of the plot thick
                while mod(tu,tc)~=0                 %To know of the rounded limit is multiple of the plot thick
                    tu = tu+add;                   %If not, then add numbers until it is
                    if tc==0.2 || tc==0.5           %If the added number is content on the ax limits then set that as the upper limit
                        if any(ax_lim ==tu)
                            break
                        end
                    end
                end

                limite(2)=tu;
            end

            if limite(2)<=0                         %Set the upper limit to the plot thick when this limit is less tan zero
                limite = [limite(1) tc];
                ylim (limite);
            end


            %Find the lower limit of the plot

            if limite(1)<=0
                if mod(limite(1),tc)==0
                    ylimit=limite(1);
                else
                    tl= round(limite(1),rnd);
                    while mod(tl,tc)~=0
                        tl=tl-add;
                        if tc==0.2 || tc==0.5
                            if any((-1*ax_lim) ==(tl))
                                break
                            end
                        end
                    end
                    limite(1)=tl;
                end
            else
                if mod(limite(1),tc)==0
                    ylimit=limite(1);
                else
                    limite(1) = limite(1)-add;
                    tu= round(limite(1),rnd);
                    limite(1)=tu;
                end

            end

            if(limite(1)>= 0) && (n==1 || n==3 || n==4 || n==8)           %Set the lower limit to -thick when the limit is higher than zero
                limite = [-tc limite(2)];
                ylim (limite);
            end

            %Plot the Right and Left Toe Off
            line([RTo RTo], limite,'Color',[0 0.6 0]);
            line([LTo LTo], limite,'Color',[0.9 0 0]);

            %  set the plot limits according to the maximun and minimun limit of the
            %  plots
            ylim (limite);
            yticks(limite(1):tc:limite(2));
            xticks(0:20:100);

            subtitle(plotname,'FontWeight','bold','FontName','Serif','Fontsize',11)
            %Set the X and Y labels
            xlabel('Ciclo [%]','FontName','Serif');
            ylabel(Yname,'FontName','Serif');
            limitey= get(hax,'YLim');

            %Set the upper and lower labels
            text(86-length(char(label2)),limitey(1)+(tc/2),label2,'FontSize',9,'FontName','Serif')
            text(86-length(char(label1)),limitey(2)-(tc/2),label1,'FontSize',9','FontName','Serif')            

            mLength = a;
        end
        
        function Plot_consis(app,type,f,n,plano,data,Rto,Lto,plotname,label1,label2,Rto2,Lto2,Rto3,Lto3)
            % Variable asignation
            data= data; % Make the data matrix into a numeric matrix
            cicle = data(:,1);

            M_R_Normal = data(:,2);
            SD_R_Normal = data(:,3);
            M_L_Normal = data(:,4);
            SD_L_Normal = data(:,5);
            R_toes=[Rto,Rto2,Rto3];
            L_toes=[Lto,Lto2,Lto3];

            % Find the maximum and minimun values of the kinematic plots and the gait
            % Global Max and min values

            R_Data = [data(:,6),data(:,8),data(:,10)];
            L_Data = [data(:,7),data(:,9),data(:,11)];


            %Creating the curves with the Mean and SD of the left and right sides
            curve1 = M_R_Normal+SD_R_Normal;
            curve2 =M_R_Normal-SD_R_Normal;
            curve3 = M_L_Normal+SD_L_Normal;
            curve4 =M_L_Normal-SD_L_Normal;

            % Creates a vector with the gate cycle from 0 to 100 and then from 100 to 0
            x=[cicle',fliplr(cicle')];

            % Creates the vectors with the curves of the right and left sides
            iBR=[curve1', flip(curve2')];
            iBL=[curve3', flip(curve4')];
            % colors = purple , Blue, red
            colors = [0.5 0 0.8 ; 0.1 0.7 0.8; 0.8 0.3 0.3];
            %colors2 = gree, red
            colors2 =[0 0.6 0 ; 0.6 0 0];

            if type ==1
                if plano ~= "Sagital" && n==1
                    close all;
                end
            end


            for i=1:3           % To plot the three pages (Right, Left and Both)
                if ~ishandle(f+i)  % Verificar si la figura ya existe
                    a = figure(f+i);  % Crear la figura sin abrirla
                else
                    set(0, 'CurrentFigure', f+i);  % Establecer la figura actual sin abrirla
                    a =gcf ;
                end


                set(gcf,'color','w');       %Sets the figure color to white

                if type == 1
                    set(gcf,'Position',[40,50,300,1200]);
                    hax=subplot(5,1,n);
                    Yname = 'Ángulo (deg)';
                else
                    set(gcf,'Position',[80,500,1000,200]);       %Sets the figure position
                    hax=subplot(1,3,n);                         %Divides the figure into 3 subplots and indictaes tha n is the subplot to be edited

                    if plano ==1
                        Yname = 'Ángulo (deg)';
                    elseif plano==2 || plano==4
                        Yname = 'Momento (N*m)';
                    elseif plano==3
                        Yname = 'Potencia (W)';
                    elseif plano==5
                        Yname = 'Fuerza (N)';
                    end
                end

                % ajusta los datos para imprimir las caracteristicas de
                % cinetica

                if type~=1
                    if (plano ==2)||(plano ==4)||(plano ==3)||(plano ==5)
                        for k=1:3
                            adj_datR=R_Data(:,k);
                            adj_datR(R_toes(k)+1:end)=nan;
                            R_Data(:,k)=adj_datR;
                            adj_datL=L_Data(:,k);
                            adj_datL(L_toes(k)+1:end)=nan;
                            L_Data(:,k)=adj_datL;
                        end

                    end
                end


                %Plots the mean and the SD as a shaded area of the left and right side
                fill(x,iBR,'k','FaceAlpha',0.2, 'EdgeColor', 'None')
                hold('on')
                fill(x,iBL,'k','FaceAlpha',0.2, 'EdgeColor', 'None')

                if i==1
                    for w=1:3
                        plot (cicle, R_Data(:,w),'Color', colors(w,:),'LineWidth',1.0);    
                        hold on;
                                             
                    end
                    limits(app,type,plano,a,i,n,hax,plotname,Yname,label1,label2,Rto,Lto,Rto2,Lto2,Rto3,Lto3)

                elseif i==2
                    for w=1:3
                        plot (cicle, L_Data(:,w),'Color', colors(w,:),'LineWidth',1.0);
                        hold on;
                    end
                    limits(app,type,plano,a,i,n,hax,plotname,Yname,label1,label2,Rto,Lto,Rto2,Lto2,Rto3,Lto3)

                else
                    for w=1:3
                        plot (cicle, R_Data(:,w),'Color', [0 0.6 0],'LineWidth',1.0);
                        hold on;
                        plot (cicle, L_Data(:,w),'Color', [0.6 0 0],'LineWidth',1.0);
                    end
                    limits(app,type,plano,a,i,n,hax,plotname,Yname,label1,label2,Rto,Lto,Rto2,Lto2,Rto3,Lto3)
                end


                %plot a Zero Line
                zer= zeros(length(cicle));
                plot(cicle, zer,'k--');

                if type == 1
                    if n==4 && plano=="Frontal"      %Only when the position of the plot is theblank space
                        hax=subplot(5,1,5);
                        if i==1 || i==2     %Legend fot the three walkings i=1 (Right), i=2 (Left)

                            trial2= app.trial;
                            for pl=1:3      %Created a plot with randon numbers to setup the three colors
                                plot(rand(1),'color',colors(pl,:),'LineWidth',2.0)
                                hold on
                                sp = split(app.trial(pl),'W');  %Name of the legends
                                trial2(pl)= append(" Walking 0",sp(2));
                            end
                            hold off
                            legend('boxoff')
                            leg=legend(trial2(1),trial2(2),trial2(3));      %Setup the name of the legend colors
                        else        % Legend for the Left and Tigh side plots
                            for pl=1:2
                                plot(rand(1),'color',colors2(pl,:),'LineWidth',2.0)
                                hold on
                            end
                            hold off
                            legend('boxoff')
                            leg=legend(' Lado Derecho', ' Lado Izquierdo');
                        end

                        set(gca,'visible','off')
                        leg.FontSize=12;

                        leg.ItemTokenSize=[90 ,100];
                        leg.LineWidth=0.01;
                        leg.FontName = 'Serif';
                        leg.Position=[0.4 0.06 0.2 0.12];
                    end
                end

                
                % Setup the legends for the kinetics
                if type == 2
                    if n==2 && plano==4      %Only when the position of the plot is theblank space
                        hax=subplot(1,3,3);
                        if i==1 || i==2     %Legend fot the three walkings i=1 (Right), i=2 (Left)
                            trial2= app.trial;
                            for pl=1:3      %Created a plot with randon numbers to setup the three colors
                                plot(rand(1),'color',colors(pl,:),'LineWidth',2.0)
                                hold on
                                sp = split(app.trial(pl),'W');  %Name of the legends
                                trial2(pl)= append(" Walking 0",sp(2));
                            end
                            hold off
                            legend('boxoff')
                            leg=legend(trial2(1),trial2(2),trial2(3));      %Setup the name of the legend colors
                        else        % Legend for the Left and Tigh side plots
                            for pl=1:2
                                plot(rand(1),'color',colors2(pl,:),'LineWidth',2.0)
                                hold on
                            end
                            hold off
                            legend('boxoff')
                            leg=legend(' Lado Derecho', ' Lado Izquierdo');
                        end

                        set(gca,'visible','off')
                        leg.FontSize=12;

                        leg.ItemTokenSize=[90 ,100];
                        leg.LineWidth=0.01;
                        leg.FontName = 'Serif';
                        leg.Position=[0.300 0.280 1 0.5000];
                    end
                end
                
                if type ==1
                    if n==4 && plano == "Frontal"
                        saveas(a,append(app.ruta,'Images\',append(plano,num2str(i)),'.png'));
                    elseif n==5
                        saveas(a,append(app.ruta,'Images\',append(plano,num2str(i)),'.png'));
                    end
                else
                    if n==2 && plano==4
                        saveas(a,append(app.ruta,'Images\',append(num2str(plano),num2str(i)),'.png'));
                    elseif n==3
                        saveas(a,append(app.ruta,'Images\',append(num2str(plano),num2str(i)),'.png'));
                        
                    end                 
                end
            end

            if type == 2
                if plano ==3 && n==3
                    close all;
                end
            end
        end
        
        function limits (~,type,plane,~,p,n,hax,name,yname,label1,label2,Rto,Lto,Rto2,Lto2,Rto3,Lto3)
                       
           %Find the closer Y limit according to the 5,10, 15 or 20 multiples
            limite= get(hax, 'YLim');
            yaxthi = get(hax, 'YTick'); % Get the Y axis vector
            tc=diff(yaxthi); % Get the difference between the Y axes number to get the width
            tc= tc(1);      % Set the width


            if tc==1 && (((abs(limite(1)-tc))<1)||((abs(limite(2)-tc))<1))
    
                tc=0.5;
            end
          

            if tc == 0.2
                ax_lim=[0.2,0.4,0.6,0.8];    %USE this to set the limits when the thick is 0.2
                add=0.1;                    %Set the numer to add to the limit until find the next addecuate limit
                rnd = 1;                    %Number of Decimals to round to
            elseif tc ==0.5
                ax_lim=[0.5,1.5,2.5,3.5,4.5,5.5,6.5]; %USE this to set the limits when the thick is 0.5
                add=0.1;                             %Set the numer to add to the limit until find the next addecuate limit
                rnd=1;                               %Number of Decimals to round to
            else
                add =1; %To add to the limit number to set the upper and lower limit
                rnd=0;  %Number of Decimals to round to
            end
            addition = (5*add)/10;
            
            if round(limite(1),2)== floor(limite(1)) 
                limite(1)= limite(1);
            else
                if abs(limite(1))<1.5 && abs(limite(1))>0.5
                    limite(1)= limite(1);
                else
                    limite(1)= round(limite(1)-addition,1);
                end
 
                 
            end

            if round(limite(2),2)== floor(limite(2)) 
                limite(2)= limite(2);
            else
                if abs(limite(2))<1.5 && abs(limite(2))>0.5
                    limite(2)= limite(2);

                else
                   limite(2)= round(limite(2)+addition,1);
                end
                
            end
            %Find the upper limit of the plot
            if mod(limite(2),tc)==0                 %To know if the limit is multiple of the plot thick
                  %If it is, then set than number as the upper limit
            else
                tu= round(limite(2),rnd);           %If not, then round the numer to the nearest and compare if it is now multiple of the plot thick
                while mod(tu,tc)~=0                 %To know of the rounded limit is multiple of the plot thick
                    tu = round(tu+add,1) ;                   %If not, then add numbers until it is
                    if tc==0.2 || tc==0.5           %If the added number is content on the ax limits then set that as the upper limit
                        if any(ax_lim ==tu)
                            break
                        end
                    end
                end

                limite(2)=tu;
            end
            if limite(2)<=0                         %Set the upper limit to the plot thick when this limit is less tan zero
                limite = [limite(1) tc];
                ylim (limite);
            end

            %Find the lower limit of the plot
            if limite(1)<=0 && limite(1) > tc*(-1)

                if mod(limite(1),tc)==0
                    ylimit=limite(1);
                else
                    tl= round(limite(1),rnd);
                    while mod(tl,tc)~=0
                        tl=tl-add;
                        tl=round(tl,rnd);
                        if tc==0.2 || tc==0.5
                            if any((-1*ax_lim) ==(tl))
                                break
                            end
                        end
                    end
                    limite(1)=tl;
                end
            elseif limite(1)<=0 && limite(1) < tc*(-1) && limite(1) > tc*(-2)

                limite(1)=tc*(-2);
            elseif limite(1)<=0 && limite(1) < tc*(-2) && limite(1) > tc*(-3)

                limite(1)=tc*(-3);
            end

            if(limite(1)>= 0)                   %Set the lower limit to -thick when the limit is higher than zero
                limite = [-tc limite(2)];
                ylim (limite);
            end


            if p==1
                %Plot the Right and Left Toe Off
                line([Rto Rto], limite,'Color',[0 0.6 0]);
                line([Rto2 Rto2], limite,'Color',[0 0.6 0]);
                line([Rto3 Rto3], limite,'Color',[0 0.6 0]);
            elseif p==2
                line([Lto Lto], limite,'Color',[0.9 0 0]);
                line([Lto2 Lto2], limite,'Color',[0.9 0 0]);
                line([Lto3 Lto3], limite,'Color',[0.9 0 0]);
            else
                line([Rto Rto], limite,'Color',[0 0.6 0]);
                line([Rto2 Rto2], limite,'Color',[0 0.6 0]);
                line([Rto3 Rto3], limite,'Color',[0 0.6 0]);
                line([Lto Lto], limite,'Color',[0.9 0 0]);
                line([Lto2 Lto2], limite,'Color',[0.9 0 0]);
                line([Lto3 Lto3], limite,'Color',[0.9 0 0]);
            end

            % set the plot limits according to the maximun and minimun limit of the
            % plots
            ylim (limite);
            yticks(limite(1):tc:limite(2));

            subtitle(name,'FontWeight','bold','FontName','Serif','Fontsize',11)
            %Set the X and Y labels
            xlabel('Ciclo [%]','FontName','Serif');
            %Set the X and Y labels
            ylabel(yname,'FontName','Serif');
            limitey= get(hax,'YLim');

            %Set the upper and lower labels

            text(92-length(char(label2)),limitey(1)+(tc/2),label2,'FontSize',9,'FontName','Serif')
            text(92-length(char(label1)),limitey(2)-(tc/2),label1,'FontSize',9','FontName','Serif')

            if type==1
                joint = ["TRONCO","PELVIS","CADERA","RODILLA"];      %Set the joint titles
                plotposition = [0.8380,0.6400,0.4420,0.2490,0.0600];    %Vector with the position of the plots so the spacng is enough
                pos = get(hax,'Position');
                pos(4) =0.11800;                %Set the height of each plot
                pos(2)=plotposition(n);         %Set the position of the plot
                set(hax,'Position',pos)
                if plane =="Frontal"
                    title(joint(n),'Fontsize',11,'FontName','Serif')    %Set the joint titles on the frontal column
                end
            elseif type==2
                if plane ==4 && n==2
                    title("MOMENTOS PLANO FRONTAL",'Fontsize',11,'FontName','Serif')
                elseif plane==5 && n==2
                    title("FUERZA DE REACCIÓN EN EL PISO",'Fontsize',11,'FontName','Serif')
                end
                plotposition = [0.0410,0.3500,0.665];    %Vector with the position of the plots so the spacng is enough
                pos = get(hax,'Position');
                pos(1) =plotposition(n);
                pos(2) = 0.1850;
                pos(3) = 0.2400;
                pos(4) = 0.6160;
                set(hax,'Position',pos)
            end


        end
       
     
        function EMGinfo = decodeEMG(~,var)

            values=ones(1,101)*-1;     % Creates a vector full of -1
            data=strrep(var.dataAttribute,'I S ','');   %Delete the I S strings
            data=strrep(data,'S','');   %Delete all the S strings
            data=str2num(data);
            inicio=data(1);             %Sets the initial index of 0s

            % To make sure the initial idex is not zero
            if inicio >= 1
                data(1)=data(1);
            else
                data(1)=1;
            end
            % To adjust the vector
            data(end +1)=1;
            inicios=data(data>=1);  %To find the index of the -1 numbers
            indices=find(data>=1);
            diferencias=diff(indices)-1;

            if inicios(1)==1
                inicios(1)=0;
            else
                inicios(1)=inicios(1);
            end

            for k =1:length(inicios)-1
                values(inicios(k)+1:diferencias(k)+inicios(k))=0;
            end

            data=array2table(values');
     
            data.Properties.VariableNames = var.labelAttribute;
            EMGinfo=data;
        end
    end           

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function REPORTEESTABILOMETRIAPushed(app, event)
            app.testype = 1;
            Folder_Info(app)

            %% Load data
              
            OA1 = readtable (append(app.path2save,'\', "OA1.txt"));       % Open eyes T01
            OA1 = table2array(OA1);            % Table to matriz
            OA2 = readtable (append(app.path2save,'\', "OA2.txt"));       %Open eyes t=2
            OA2 = table2array (OA2);           % Table to matriz
            AEl = readtable (append(app.path2save,'\', "Areas Elipse.txt"));
            AEl = table2array(AEl); 

            try 
                  OC1 = readtable (append(app.path2save,'\', "OC1.txt"));       % closed eyes T01
                  OC1 = table2array(OC1); 
                  OC2 = readtable (append(app.path2save,'\', "OC2.txt"));      % closew eyes T02
                  OC2 = table2array(OC2);
                  app.Flag_OC = 1;                      %This means there are OC trials

            catch ME                                %In case there are OC files the UI asks to check if there is an error
                  if (strcmp(ME.identifier,'MATLAB:textio:textio:FileNotFound'))
                      answer = questdlg('El paciente tiene prueba de Ojos cerrados?',...
                                        'Opciones',...
                                        'Si','No',' ');
                      if answer == "Si"
                          msg = 'Por favor verificar que las tomas de ojos cerrados esten en la carpeta';
                          msgbox(msg,'Error en lectura de archivos','warn');
                      elseif answer == "No"
                          app.Flag_OC = 0;          %this means there are no OC trials
                      end                                            
                  end
            end 

            Info_OA1 = Estab_Variables(app,OA1);    % Call the Function to get the variables fo OA1
            Info_OA1= [Info_OA1,AEl(1)];
            Info_OA2 = Estab_Variables(app,OA2);    % Call the Function to get the variables fo OA2
            Info_OA2= [Info_OA2,AEl(2)];
            Info_OA = [(Info_OA1(1)+Info_OA2(1))/2 , (Info_OA1(2)+Info_OA2(2))/2, (Info_OA1(3)+Info_OA2(3))/2,(Info_OA1(4)+Info_OA2(4))/2,(Info_OA1(5)+Info_OA2(5))/2];  % Get the mean values            
            app.Stab_Data = [Info_OA1;Info_OA2;Info_OA]; 
            

            %To view on a table
            Info_OA1 = ['Ojos abiertos T1' , string(Info_OA1)];     
            Info_OA2 = ['Ojos abiertos T2' , string(Info_OA2)];
            Info_OA = ['Ojos abiertos Promedio' , string(Info_OA)];
            %Create the names of the second row
            Row1 = [" " , "ML","ML","AP"," ","Global"];
            

            if app.Flag_OC == 1
                Info_OC1 = Estab_Variables(app,OC1);
                Info_OC1= [Info_OC1,AEl(3)];
                Info_OC2 = Estab_Variables(app,OC2);
                Info_OC2= [Info_OC2,AEl(4)];
                Info_OC = [(Info_OC1(1)+Info_OC2(1))/2 ,(Info_OC1(2)+Info_OC2(2))/2, (Info_OC1(3)+Info_OC2(3))/2,(Info_OC1(4)+Info_OC2(4))/2,(Info_OC1(5)+Info_OC2(5))/2]; % Get the mean values
                app.Stab_Data = [app.Stab_Data;Info_OC1;Info_OC2;Info_OC];
                 

                Info_OC1 = ['Ojos cerrados T1' , string(Info_OC1)];
                Info_OC2 = ['Ojos cerrados T2' , string(Info_OC2)];
                Info_OC = ['Ojos cerrados Promedio' , string(Info_OC)];

                %Create the table   
                app.StabTable.Data = [Row1;Info_OA1;Info_OA2;Info_OA;Info_OC1;Info_OC2;Info_OC];        %Display on table all the Trials
            else
                app.StabTable.Data = [Row1;Info_OA1;Info_OA2;Info_OA];               %Display on table only OA trials
            end
            a = app.Stab_Data; 

            app.TabGroup.SelectedTab = app.StabilomTab;                    %Go to Stabilometry Tab
            
        end

        % Callback function
        function ReporTGenerStabilButtonPushed(app, event)
            import mlreportgen.report.*                %Import the libraries
            import mlreportgen.dom.*     

            %Means the test is Stabilometry
            R = PatientInfoTable(app);

            %Add a Space
            space= Text(' ');
            space.Style = {LineSpacing(4)};
            add(R, space);

            %Table Style
            tbl_Stab_Style= {HAlign('center'), Border('solid'), ColSep('solid'), RowSep('solid'),LineSpacing('0.5cm')};         %Set the table style to add later
            HeaderRowStyle = {Bold,VAlign('middle'),HAlign('center'),FontFamily('Cambria')};        %Set the Style for the Main Header
            HeaderRowStyle2 = {VAlign('middle'),HAlign('center'),FontFamily('Cambria')};        %Set the Style for the Main Header
            OtherText = {OuterMargin('0pt', '0pt', '0pt', '0pt'), FontFamily('Cambria')};

            %HEADER WITH PROCESED DATA         

            tble = Table(6);            %Creates a table with 4 columns
            tble.Style = [tble.Style tbl_Stab_Style];        %Set the Style to the table 

            grps(1) = TableColSpecGroup;
            
            specs(1) = TableColSpec;            
            specs(1).Style = {Width("18%")};
            specs(2) = TableColSpec;            
            specs(2).Style = {Width("18%")};
            specs(3) = TableColSpec;
            specs(3).Style = {Width("18%"),InnerMargin('9pt', '9pt', '1pt', '1pt')};
            specs(4) = TableColSpec;            
            specs(4).Style = {Width("18%")};
            specs(5) = TableColSpec;
            specs(5).Style = {Width("18%"),InnerMargin('15pt', '15pt', '1pt', '1pt')};
            specs(6) = TableColSpec;            
            specs(6).Style = {Width("18%")};
           

            grps(1).ColSpecs = specs;

            tble.ColSpecGroups = grps;
                      
            %Table First Rows
            r=TableRow;     %Defines the first Table row
            r.Style = [r.Style HeaderRowStyle];          %Sets the Table Row to the predefined Style
            inf = Text('Prueba');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add first Column name   
            te.RowSpan=2;
            append(r,te);          

            inf = Text('Rango (mm)');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add second Column name             
            append(r,te);

            inf = Paragraph(('Desplazamiento Radial(mm)'));
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add third Column name     
            append(r,te);

            inf = Text('Velocidad (mm/s)');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add fourth Column name       
            append(r,te);

            inf = Text(' Área/Segundo (mm^2/s)');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add fourth Column name  
            te.RowSpan =2;
            append(r,te);

            inf = Text('Área elipse (mm^2)');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);            %Add fourth Column name       
            append(r,te);
            add(R,tble);
            append(tble,r);

            %Table Second Rows
            r=TableRow;     %Defines the first Table row
            r.Style = [r.Style HeaderRowStyle];          %Sets the Table Row to the predefined Style
            inf = Text('ML');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);

            inf = Text('ML');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);

            inf = Text('AP');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);

            inf = Text('Global');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);
            append(tble,r);

            %Table Data Open Eyes T1
            r=TableRow;     %Defines the first Table row
            r.Style = [r.Style HeaderRowStyle2];          %Sets the Table Row to the predefined Style
            inf = Text('Ojos abiertos 1');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(1,1)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(1,2)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);  

            inf = Text(string(app.Stab_Data(1,3)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(1,4)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);    

            inf = Text(string(app.Stab_Data(1,5))); %FALTA ESTE VALOR
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);            
            append(tble,r);

             %Table Data Open Eyes T2

            r=TableRow;     %Defines the first Table row
            r.Style = [r.Style HeaderRowStyle2];          %Sets the Table Row to the predefined Style
            inf = Text('Ojos abiertos 2');
            inf.Style= [inf.Style HeaderRowStyle];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(2,1)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(2,2)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);  

            inf = Text(string(app.Stab_Data(2,3)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(2,4)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(2,5)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);            
            append(tble,r);

            %Table Data Open Eyes Mean values

            r=TableRow;     %Defines the first Table row
            r.Style = {HAlign('center'),VAlign('middle'),InnerMargin('18pt', '18pt', '1pt', '1pt'),FontFamily('Cambria')}  ;       %Sets the Table Row to the predefined Style
            inf = Text('Ojos abiertos Promedio');
            inf.Style= {Bold};
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(3,1)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(3,2)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);  

            inf = Text(string(app.Stab_Data(3,3)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(3,4)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);

            inf = Text(string(app.Stab_Data(3,5)));
            inf.Style= [inf.Style OtherText];
            te = TableEntry(inf);
            append(r,te);            
            append(tble,r);

            if app.Flag_OC == 1
                %Table Data close Eyes t1
                r=TableRow;     %Defines the first Table row
                r.Style = [r.Style HeaderRowStyle2];          %Sets the Table Row to the predefined Style
                inf = Text('Ojos cerrados 1');
                inf.Style= [inf.Style HeaderRowStyle];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(4,1)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(4,2)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(4,3)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(4,4)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(4,5)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);                
                append(tble,r);

                %Table Data close Eyes t1
                r=TableRow;     %Defines the first Table row
                r.Style = [r.Style HeaderRowStyle2];          %Sets the Table Row to the predefined Style
                inf = Text('Ojos cerrados 1');
                inf.Style= [inf.Style HeaderRowStyle];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(5,1)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(5,2)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(5,3)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(5,4)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(5,5)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);                
                append(tble,r);

                %Table Data close Eyes Mean t1
                r=TableRow;     %Defines the first Table row
                r.Style = {HAlign('center'),VAlign('middle'),InnerMargin('18pt', '18pt', '1pt', '1pt'),FontFamily('Cambria')};
                inf = Text('Ojos cerrados Promedio');
                inf.Style= {Bold};
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(6,1)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(6,2)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(6,3)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(6,4)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);

                inf = Text(string(app.Stab_Data(6,5)));
                inf.Style= [inf.Style OtherText];
                te = TableEntry(inf);
                append(r,te);                
                append(tble,r);
            end       
            close (R)
            
        end

        % Button pushed function: REPORTEGRFICASC
        function REPORTEGRFICASCPushed(app, event)

            app.ruta='C:\Users\marcha\Desktop\Codigos Matlab\';
            delete(gcf)
            app.ERRORESLabel.Visible="off";
            
            set(0, 'DefaultFigureVisible', 'off');
           
            try
                rmdir(append(app.ruta,'images'),'s');
            catch
                
            end

       
            mkdir(append(app.ruta,'images'));
            Folder_Info(app)


            app.testype = 5;

            data_cycle = ["angle","emg","track1d","vel1d","force1d","torque1d","power"];       %Array with the cycle type variable names
            data_static = ["track1d","scalar","vel1d","time","freq","angle"];
            data_stream = "spectrum";
            
            %Array with the variable names 
            Var_names= ["Angles1","EMG","M_Lengths","M_Veloc","Force1","Torque1","Power1","Lengths","Scalar","Velocity","Times","Frequencies","DAngles","FFT",...
                "Angles2","Angles3","Cadence3","Scalar2","Scalar3","ScalarP","Point1D3","Times3","Velocity3","Torque2","Torque3","Force2","Force3","Power2","Power3"];
            
            %% LOAD NORMAL DATA
          
            disp(app.NormativadeadultoCheckBox.Value)

            if app.P_Info.age > 17 || app.NormativadeadultoCheckBox.Value==1                     %Load children or adult normal data depending to the age
                normal = readstruct(append(app.ruta,'Extraccion\DavisDML-adults.xml'));
            else
                normal = readstruct(append(app.ruta,'Extraccion\DavisDML-children.xml'));
            end

            %Create a waitbar
            barr=waitbar(0,'Loading normal data...');
            n_root = normal.norm;            
            loadNormal(app,n_root,'cycle',5,data_cycle,0,Var_names);
   

            waitbar(0.5,barr,'Loading normal data...');
            loadNormal(app,n_root,'static',5,data_static,7,Var_names);
         
            waitbar(1,barr,'Loading normal data...');
            close(barr)
    
% 
            %% LOAD PATIENT DATA
            %Save the Walking trials
            try
                for w=1:3       %To choose the 5th, 6th and 7th position
                    num=strrep(app.allfiles(w+5).name,'W','');
                    num=strrep(num,'.xml','');
                    app.trial(w)=str2num(num);
                    app.Walking.(append('W',string(app.trial(w))))=readstruct(append(app.path,'/','W',string(app.trial(w)),'.xml'));
                end

                %Organize the trial vector, so the fisrst value is the selected trial

                in= find(app.trial==app.Selec_trial);
                in2=find(app.trial~=app.Selec_trial);
                app.trial=[append('W',string(app.trial(in))),append('W',string(app.trial(in2(1)))),append('W',string(app.trial(in2(2))))];

                loadData(app,'cycle',data_cycle,Var_names,0);
                loadData(app,'static',data_static,Var_names,7);
                loadData(app,'stream',data_stream,Var_names,13);
            catch
                app.ERRORESLabel.Text='Error al cargar la informacion de los walking ';
                app.ERRORESLabel.Visible="on";
                return
            end


            %% VARIABLE ASIGNATION
            RTo = round((app.P_Variables.(app.trial(1)).Scalar.("sRSTANCE"))*100,2);
            LTo = round((app.P_Variables.(app.trial(1)).Scalar.("sLSTANCE"))*100,2);
            RTo2 = round((app.P_Variables.(app.trial(2)).Scalar.("sRSTANCE"))*100,2);
            LTo2 = round((app.P_Variables.(app.trial(2)).Scalar.("sLSTANCE"))*100,2);
            RTo3 = round((app.P_Variables.(app.trial(3)).Scalar.("sRSTANCE"))*100,2);
            LTo3 = round((app.P_Variables.(app.trial(3)).Scalar.("sLSTANCE"))*100,2);
            Sample= (0:100)';

            % Structure with kinematic variable names
            kmt_names = struct();
            kmt_names.Sagital = ["TRT", "PTILT", "HPFE", "KFE", "AFE"];
            kmt_names.Frontal = ["TOBL", "POBLI", "HPAA", "KAA"];
            kmt_names.Transverse = ["TROT", "PROT", "HPIE", "KIE", "AIE"];
            
            % Structure with kinematic titles names
            kmt_titles = struct();
            kmt_titles.Sagital = ["Inclinación" , "Inclinación", "Flexión-Extensión","Flexión-Extensión","Flexión-Extensión"];
            kmt_titles.Frontal = ["Oblicuidad" , "Oblicuidad", "Abducción-Aducción","Valgo-Varo"];
            kmt_titles.Transverse = ["Rotación" , "Rotación", "Rotación","Rotación","Progresión del Pie"];

            % Structure with labels for kinematic
            kmt_label=struct();
            kmt_label.Sagital = ["Post.","Ant.","Ant.","Post.","Flex.","Ext.","Flex.","Ext.","Dorsif.","Plantif."];
            kmt_label.Frontal = ["Arriba","Abajo","Sup.","Inf.","Add.","Abd.","Varo","Valgo"];
            kmt_label.Transverse = ["Int.","Ext.","Int.","Ext.","Int.","Ext.","Int.","Ext.","Int.","Ext."];

             %Structutr with kinetic variables names
            knt_names = struct();
            prev = ["ac","tc","pc","tc","fc"];     %Leters before the name
            knt_names.Angles1 = ["HPFE", "KFE", "AFE"];
            knt_names.Torque1 = ["HPFE","KFE","AFE"];
            knt_names.Power1 = ["HPFE","KFE","AFE"];
            knt_names.Force1 = ["GRAP","GRVE","GRML"];
            

            % Structure with labels for kinematic
            knt_label=struct();
            knt_label.Angles1 = ["Flex.","Ext.","Flex.","Ext.","Dorsif.","Plantif."];
            knt_label.Torque1 = ["Ext.","Flex.","Ext.","Flex.","Planti.","Dorsif."];
            knt_label.Power1 = ["Gen.","Abs.","Gen.","Abs.","Gen.","Abs."];            
            knt_label.Force1 = ["Ant.","Post.","Vert."," ","Med.","Lat."];

            % Structure with kinetic titles names
            knt_titles = struct();
            knt_titles.Angles1 = ["Cadera Flex.-Ext." , "Rodilla Flex.-Ext.","Tobillo Flex.-Ext."];
            knt_titles.Torque1 = ["Momento Cadera" , "Momento Rodilla", "Momento Tobillo"];
            knt_titles.Power1 = ["Potencia Cadera" , "Potencia Rodilla", "Potencia Tobillo"];
            knt_titles.Force1 = ["Fuerza Antero-Posterior" , "Fuerza Vertical", "Fuerza Medio-Lateral"];

            % Structure with EMG variable names
            EMG_variab = ["Rectus femoris","Semitendinosus","Tibialis anterior","Gastrocnemius medialis"];
            %Structure with EMG title names
            EMG_title = ["Recto femoral","Semitendinoso","Tibial anterioir", "Gastronemio"];

            % Structure with FFT variable names
            FFT_variab = ["RRF","RST","RTA","RGM","LRF","LST","LTA","LGM"];

             %Structure with Muscle lenght variable names
            l_musc1 = ["HPAA","GMD1","HPFE","KFE","RF","ST","BFCL","AFE","GSM","GSL"];
            l_titles = ["Cadera Abd.-Add.","Glúteo medio","Cadera Flex.-Ext.","Rodilla Flex.-Ext."...
                "Recto femoral","Semitendinoso","Bicep femoral","Tobillo Flex.-Ext.","Gastronemio medial","Gastronemio lateral"];
            l_labels = ["Add.","Abd"," "," ","Flex.","Ext.","Flex.","Ext."," "," "," "," "," "," ","Dorsif.","Plantif."," "," "," "," "];


            %% PLOT KINEMATICS
            page = 1;                %Indicates the page to be plotted is kinematics
            Walking1=app.P_Variables.(app.trial(1));

            for p=app.planos
                lb = 0;                                 % Index to go through kmt labels
               % length(kmt_names.(p))  
                 for i =1:length(kmt_names.(p)) 
                     data =[Sample, app.N_Variables.Angles1.(append("aR",kmt_names.(p)(i),".M")),app.N_Variables.Angles1.(append("aR",kmt_names.(p)(i),".S"))...
                         app.N_Variables.Angles1.(append("aL",kmt_names.(p)(i),".M")),app.N_Variables.Angles1.(append("aL",kmt_names.(p)(i),".S"))...
                         Walking1.Angles1.(append("acR",kmt_names.(p)(i))),Walking1.Angles1.(append("acL",kmt_names.(p)(i)))];
                     
                     fig = Plot_KinmatGraps(app,p,i,data,page,RTo,LTo,kmt_titles.(p)(i),kmt_label.(p)(lb+1),kmt_label.(p)(lb+2));
                     lb = lb+2;
                 end                       
                 saveas(fig,append(app.ruta,'Images\',p,'.png'));
            end 
            app.GrficasdecinemticaCheckBox.Value=1;
            app.GrficasdecinemticaCheckBox.FontColor=[0.39,0.83,0.07];


            % PLOT KINETICS  
            if app.Kinet_individual ==1
               
                page = 2;
%                 for l = 2

                for l=1:length(app.lines)
                    lb=0;   %Index to go through kinetic label
                    % i=1:length(knt_names.(app.lines(l)))
                    for i=1:length(knt_names.(app.lines(l)))
                        if l == 3          %Change the variables on the -torque to be the frontal plane
                            knt_names.Torque1 = ["HPAA","KAA"];
                            knt_label.Torque1 = ["Abd.","Add.","Valgo","Varo"];
                            knt_titles.Torque1 = ["Momento Cadera", "Momento Rodilla"];
                        end
                        if l==1
                            data = [Sample,app.N_Variables.(app.lines(l)).(append("aR",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("aR",knt_names.(app.lines(l))(i),".S"))...
                                app.N_Variables.(app.lines(l)).(append("aL",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("aL",knt_names.(app.lines(l))(i),".S"))...
                                Walking1.(app.lines(l)).(append(prev(l),"R",knt_names.(app.lines(l))(i))),Walking1.(app.lines(l)).(append(prev(l),"L",knt_names.(app.lines(l))(i)))];
                        else
                            data = [Sample,app.N_Variables.(app.lines(l)).(append("R",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("R",knt_names.(app.lines(l))(i),".S"))...
                                app.N_Variables.(app.lines(l)).(append("L",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("L",knt_names.(app.lines(l))(i),".S"))...
                                Walking1.(app.lines(l)).(append(prev(l),"R",knt_names.(app.lines(l))(i))),Walking1.(app.lines(l)).(append(prev(l),"L",knt_names.(app.lines(l))(i)))];
                        end
                        %                     Plot Kinetic graphs
                        %                     l= Line to be plotted
                        %                     i = plot number
                        %                     data = data to be plotted
                        %                     page = Indicates the page to be plotted
                        %                     (1:kinematics, 2:kinetics, 3: EMG)
                        %                     RTo and LTo Toe off events
                        fig2 = Plot_KinmatGraps(app,l,i,data,page,RTo,LTo,knt_titles.(app.lines(l))(i),knt_label.(app.lines(l))(lb+1),knt_label.(app.lines(l))(lb+2));
                        lb = lb+2;

                    end
                    if l==4
                        saveas(fig2,append(app.ruta,'Images\',app.lines(l),'2.png'));
                    else
                        saveas(fig2,append(app.ruta,'Images\',app.lines(l),'.png'));
                    end

                end
            app.GrficasdeCinticaCheckBox.Value=1;
            app.GrficasdeCinticaCheckBox.FontColor=[0.39,0.83,0.07];
            end

            if app.emg==1
                %         Define the sample vector
                lg = size(Walking1.EMG);
                sampleEMG = (1:lg(1));      %Creates a vector for the EMG sampling
                sample = sampleEMG*0.2; %normalize the vector to 100%

                page = 3;
                pos = 1;
                for m=1:4
                    Rsignal = round((Walking1.EMG.(append('Right ',EMG_variab(m),'~4')))*1000000,2);        % Set the units to uV
                    Lsignal = round((Walking1.EMG.(append('Left ',EMG_variab(m),'~4')))*1000000,2);          % Set the units to uV
                    Normal = app.N_Variables.EMG.(append(EMG_variab(m),".M"));
                    EMG_plot = PlotEMG(app,4,pos,sample,Rsignal,Lsignal,Normal,RTo,LTo,EMG_title(m));
                    pos = pos+3;
                end
                saveas(EMG_plot,append(app.ruta,'Images\EMG_env.png'));

                    page = 4;
                    %Define the sample variable from the FFT data
                    sample = Walking1.Frequencies.("freq");
               
                    RFFT = Walking1.FFT.(FFT_variab(1));
                    sampleFFT = (1:length(RFFT))';
                    sampleFFT = (sampleFFT*100)/length(RFFT);

                    pos =1;
                    for i=1:4
                        RFFT = Walking1.FFT.(FFT_variab(i));
                        LFFT = Walking1.FFT.(FFT_variab(i+4));
                        FFT_plot = PlotFFT(app,9,pos,sampleFFT,RFFT,LFFT,Walking1.Frequencies.(append("Median ",FFT_variab(i))),Walking1.Frequencies.(append("Median ",FFT_variab(i+4))),EMG_title(i));
                        pos = pos+3;
                    end

                    saveas(FFT_plot,append(app.ruta,'Images\FFT.png'));
            app.GrficasdeElectromiografiaCheckBox.Value=1;
            app.GrficasdeElectromiografiaCheckBox.FontColor=[0.39,0.83,0.07];
       
            end

        page = 5;
        f=10;
        lab = 1;
        for i=1:10
            if  i == 2 || i==7 || i==9 || i==10 || i==5 || i==6 
                data = [Sample,Walking1.M_Lengths.(append("dcR",l_musc1(i))),Walking1.M_Lengths.(append("dcL",l_musc1(i)))];           
            elseif i ==4 || i==8
                data = [Sample,app.N_Variables.Angles1.(append("aR",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aR",l_musc1(i),".S"))...
                    app.N_Variables.Angles1.(append("aL",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aL",l_musc1(i),".S"))...
                    Walking1.Angles1.(append("acR",l_musc1(i))),Walking1.Angles1.(append("acL",l_musc1(i)))];
            else
                data = [Sample,app.N_Variables.Angles1.(append("aR",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aR",l_musc1(i),".S"))...
                    app.N_Variables.Angles1.(append("aL",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aL",l_musc1(i),".S"))...
                    Walking1.Angles1.(append("acR",l_musc1(i))),Walking1.Angles1.(append("acL",l_musc1(i)))];
            end

            % See if in some ocation when need to verify for Nans
            %     numind = cellfun(@isnumeric, data);
            %     data(~numind) ={NaN};

            ML_Plot = Plot_muscle(app,f,i,data,RTo,LTo,l_titles(i),l_labels(lab),l_labels(lab+1));
            lab=lab+2;
            if i == 4
                saveas(ML_Plot,append(app.ruta,'Images\M_Length.png'));
                f=11;
            end
        end
        saveas(ML_Plot,append(app.ruta,'Images\M_Length2.png'));
        

        page = 6;
        f=12;
        lab = 1;
        for i=1:10
            if  i == 2 || i==7 || i==9 || i==10 || i==5 || i==6 
                data = [Sample,Walking1.M_Veloc.(append("velcR",l_musc1(i))),Walking1.M_Veloc.(append("velcL",l_musc1(i)))];           
            elseif i ==4 || i==8
                data = [Sample,app.N_Variables.Angles1.(append("aR",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aR",l_musc1(i),".S"))...
                    app.N_Variables.Angles1.(append("aL",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aL",l_musc1(i),".S"))...
                    Walking1.Angles1.(append("acR",l_musc1(i))),Walking1.Angles1.(append("acL",l_musc1(i)))];
            else
                data = [Sample,app.N_Variables.Angles1.(append("aR",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aR",l_musc1(i),".S"))...
                    app.N_Variables.Angles1.(append("aL",l_musc1(i),".M")),app.N_Variables.Angles1.(append("aL",l_musc1(i),".S"))...
                    Walking1.Angles1.(append("acR",l_musc1(i))),Walking1.Angles1.(append("acL",l_musc1(i)))];
            end

%             numind = cellfun(@isnumeric, data);
%             data(~numind) ={NaN};

            ML_Plot = Plot_muscle(app,f,i,data,RTo,LTo,l_titles(i),l_labels(lab),l_labels(lab+1));
            lab=lab+2;
            if i == 4
                saveas(ML_Plot,append(app.ruta,'Images\M_Vel.png'));
                f=13;
            end
        end

        saveas(ML_Plot,append(app.ruta,'Images\M_Vel2.png'));
        app.GrficasdelongitudesmuscularesCheckBox.Value=1;
        app.GrficasdelongitudesmuscularesCheckBox.FontColor=[0.39,0.83,0.07];
    

%       Plot consistency
        page=7;
%         Kinematics
        for p=app.planos
            l=1;
            for i=1:length(kmt_names.(p))
%             for i=1:1
                data = [];
                dataN = [Sample,app.N_Variables.Angles1.(append("aR",kmt_names.(p)(i),".M")),app.N_Variables.Angles1.(append("aR",kmt_names.(p)(i),".S"))...
                    app.N_Variables.Angles1.(append("aL",kmt_names.(p)(i),".M")),app.N_Variables.Angles1.(append("aL",kmt_names.(p)(i),".S"))];
                for j=1:3
                    data = [data,app.P_Variables.(app.trial(j)).Angles1.(append("acR",kmt_names.(p)(i))),app.P_Variables.(app.trial(j)).Angles1.(append("acL",kmt_names.(p)(i)))];
                end
                data =[dataN,data];
%                 Plot_consis(app,type,f,n,plano,data,Rto,Lto,plotname,label1,label2,Rto2,Lto2,Rto3,Lto3)
%                 type: indicates if the plots are kinematics or kinetics
%                 f: number of figure , n: subplot, 
%                 plano: in kinematics indicates the plane and in kinetics
%                 indicates the line to be plotted
%                 data : Matrix with the data
%                 Rto and Lto: Toe offs, plotname: name on the plot,
%                 labels: labels on the plot
                Plot_consis(app,1,14,i,p,data,RTo,LTo,kmt_titles.(p)(i),kmt_label.(p)(l),kmt_label.(p)(l+1),RTo2,LTo2,RTo3,LTo3);
                l=l+2;
          
            end
    
        end
        app.GrficastestdeconsistenciacinemticaCheckBox.Value=1;
        app.GrficastestdeconsistenciacinemticaCheckBox.FontColor=[0.39,0.83,0.07];


        % Change again the Kinetic.Torque1 variables
        knt_names.Torque1 = ["HPFE","KFE","AFE"];
        knt_label.Torque1 = ["Ext.","Flex.","Ext.","Flex.","Planti.","Dorsif."];
        knt_titles.Torque1 = ["Momento Cadera" , "Momento Rodilla", "Momento Tobillo"];

%       Kinetics
        if app.kinet==3
%             for l=1:length(app.lines)     % To go over the 5 lines
            for l=1:5
                p=1;
                for i=1:length(knt_names.(app.lines(l)))      % To go over the3 planes
                    %             for i=1:1
                    data = [];
                    if l == 1
                        dataN = [Sample,app.N_Variables.(app.lines(l)).(append("aR",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("aR",knt_names.(app.lines(l))(i),".S"))...
                            app.N_Variables.(app.lines(l)).(append("aL",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("aL",knt_names.(app.lines(l))(i),".S"))];
                    else
                        dataN = [Sample,app.N_Variables.(app.lines(l)).(append("R",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("R",knt_names.(app.lines(l))(i),".S"))...
                            app.N_Variables.(app.lines(l)).(append("L",knt_names.(app.lines(l))(i),".M")),app.N_Variables.(app.lines(l)).(append("L",knt_names.(app.lines(l))(i),".S"))];
                    end
        
                    if l == 3          %Change the variables on the -torque to be the frontal plane
                        knt_names.Torque1 = ["HPAA","KAA"];
                        knt_label.Torque1 = ["Abd.","Add.","Valgo","Varo"];
                        knt_titles.Torque1 = ["Momento Cadera", "Momento Rodilla"];
                    end
                    for j=1:3
                        data = [data,app.P_Variables.(app.trial(j)).(app.lines(l)).(append(prev(l),"R",knt_names.(app.lines(l))(i))),app.P_Variables.(app.trial(j)).(app.lines(l)).(append(prev(l),"L",knt_names.(app.lines(l))(i)))];
        
                        %                      if app.lines(l) == "Torque1" || app.lines(l)== "Power1" || app.lines(l)== "Force1"
                        %                          numind = cellfun(@isnumeric, data);
                        %                          data(~numind) ={NaN};
                        %                      end
                    end
                    data =[dataN,data];
                    Plot_consis(app,2,23,i,l,data,RTo,LTo,knt_titles.(app.lines(l))(i),knt_label.(app.lines(l))(p),knt_label.(app.lines(l))(p+1),RTo2,LTo2,RTo3,LTo3)
                    
                    p=p+2;
                    
                end
                
            end
           app.GrficastestdeconsistenciacinticaCheckBox.Value=1;
           app.GrficastestdeconsistenciacinticaCheckBox.FontColor=[0.39,0.83,0.07];
        end
        app.CONTINUARButton.Visible="on";


        close all;


        end

        % Button pushed function: ReporTGenerGraphs
        function ReporTGenerGraphsButtonPushed(app, event)
            import mlreportgen.report.*                %Import the libraries
            import mlreportgen.dom.*
            import mlreportgen.utils.*
            h = waitbar(0, 'Procesando...');
            app.testype = 5;
            % Means the test is Plot reports
            app.PageNumber =1;
          
            R = PatientInfoTable(app,0);
     

            %Add a Space
            space2= Text(' ');
            space2.Style = {LineSpacing(2)};
            add(R, space2);


            %Table Style
            HeaderRowStyle = {Bold,VAlign('middle'),HAlign('center'),FontFamily('Cambria')};        %Set the Style for the Main Header
            FtClmStyle = {Bold,VAlign('middle'),HAlign('center'),FontFamily('Cambria')};
            OtherText = {FontFamily('Cambria'),LineSpacing('1.1cm'),HAlign('center')};

            %TABLE HEADER
            tble1 = Table(5);            %Creates a table 1 with 5 columns for the page 1 (temporal parameters)
            tble1.Style = [tble1.Style app.tableStyle];        %Set the Style to the table

            grps(1) = TableColSpecGroup;    %Saves the variable for the col separation


            specs3(1) = TableColSpec;
            specs3(1).Style = {Width("34%"),HAlign('center'),Bold};    %Set the first column to be wider than the others
            specs3(2) = TableColSpec;
            specs3(2).Style = {Width("16%"),HAlign('center'),Color("green")};
            specs3(3) = TableColSpec;
            specs3(3).Style = {Width("16%"),HAlign('center'),Color("red")};
            specs3(4) = TableColSpec;
            specs3(4).Style = {Width("16%"),HAlign('center')};
            specs3(5) = TableColSpec;
            specs3(5).Style = {Width("16%"),HAlign('center')};

            waitbar(0.3,h,"generando reporte 3");

            grps(1).ColSpecs = specs3;
            tble1.ColSpecGroups = grps;     %Set the column widths to the table

            %Table 1 for Temporal parameters First Row
            r1 =TableRow;     %Defines the first Table row
            % TableInput(app,row,tx,Styl,col,lspac)
            %row = Table row to be filled
            % tx = Text to be written
            % Styl= Text style
            % col = Columns to be merged
            %lspac = Linespacing
            r1 = TableInput(app,r1,'PARÁMETROS TEMPORALES',HeaderRowStyle,3,2);
            r1 = TableInput(app,r1,'VALORES NORMALES',HeaderRowStyle,2,2);
            append(tble1,r1);
            waitbar(0.4,h,"generando reporte 4");

            %Table 1 Second Rows
            r2=TableRow;     %Defines the first Table row
            r2 = TableInput(app,r2,' ',app.otherTextStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,2);
            append(tble1,r2);
            add(R,tble1);

            %Add a Space
            space1= Text(' ');
            space1.Style = {LineSpacing(1)};
            add(R, space1);

            Walking1=app.P_Variables.(app.trial(1));

            % Variable names of Temporal parameters
            TRow1 = ["FASE DE APOYO (%)",string((Walking1.Scalar.("sRSTANCE"))*100),string((Walking1.Scalar.("sLSTANCE"))*100), append(string(round(app.N_Variables.Scalar.("sRSTANCE.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sRSTANCE.S"),3)*100)),append(string(round(app.N_Variables.Scalar.("sLSTANCE.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sLSTANCE.S"),3)*100))];
            TRow2 = ["FASE DE VUELO (%)",string((Walking1.Scalar.("sRSWING"))*100),string((Walking1.Scalar.("sLSWING"))*100), append(string(round(app.N_Variables.Scalar.("sRSWING.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sRSWING.S"),3)*100)),append(string(round(app.N_Variables.Scalar.("sLSWING.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sLSWING.S"),3)*100))];
            TRow3 = ["DOBLE APOYO (%)",string((Walking1.Scalar.("sRDBLSTANCE"))*100),string((Walking1.Scalar.("sLDBLSTANCE"))*100), append(string(round(app.N_Variables.Scalar.("sRDBLSTANCE.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sRDBLSTANCE.S"),3)*100)),append(string(round(app.N_Variables.Scalar.("sLDBLSTANCE.M"),3)*100),' ± ',string(round(app.N_Variables.Scalar.("sLDBLSTANCE.S"),3)*100))];
            TRow4 = ["DURACIÓN APOYO (s)",string(round(Walking1.Times.("tseqRSTANCE"),2)),string(round(Walking1.Times.("tseqLSTANCE"),2)), append(string(round(app.N_Variables.Times.("tseqRSTANCE.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqRSTANCE.S"),2))),append(string(round(app.N_Variables.Times.("tseqLSTANCE.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqLSTANCE.S"),2)))];
            TRow5 = ["DURACIÓN BALANCEO (s)",string(round(Walking1.Times.("tseqRSWING"),2)),string(round(Walking1.Times.("tseqLSWING"),2)), append(string(round(app.N_Variables.Times.("tseqRSWING.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqRSWING.S"),2))),append(string(round(app.N_Variables.Times.("tseqLSWING.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqLSWING.S"),2)))];
            TRow6 = ["TIEMPO DE ZANCADA (s)",string(round(Walking1.Times.("tseqRSTRIDE"),2)),string(round(Walking1.Times.("tseqLSTRIDE"),2)), append(string(round(app.N_Variables.Times.("tseqRSTRIDE.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqRSTRIDE.S"),2))),append(string(round(app.N_Variables.Times.("tseqLSTRIDE.M"),2)),' ± ',string(round(app.N_Variables.Times.("tseqLSTRIDE.S"),2)))];

            %Crates a table with all the temporal parameters
            T_esp_par= FormalTable([TRow1;TRow2;TRow3;TRow4;TRow5;TRow6]);
            T_esp_par.ColSpecGroups= grps;
            T_esp_par.Style = OtherText;
            add(R,T_esp_par);


            %Creates a different table for the cadence since it only has 3 %columns
            TRow7 = ["CADENCIA (pasos/min)",string(round(Walking1.Frequencies.('fqCADENCE'),2)*60),append(string(round(app.N_Variables.Frequencies.("fqCADENCE.M"),3)*60),' ± ',string(round(app.N_Variables.Frequencies.("fqCADENCE.S"),3)*60))];

            T_temp_par2 =FormalTable(TRow7);
            T_temp_par2.Style = OtherText;

            %Set the style for the cadence table
            grps2(1) = TableColSpecGroup;
            %Specifications for each column for the Cadence Table
            specs2(1) = TableColSpec;
            specs2(1).Style = {Width("34%"),HAlign('center'),Bold};
            specs2(2) = TableColSpec;
            specs2(2).Style = {Width("33%"),HAlign('center')};
            specs2(3) = TableColSpec;
            specs2(3).Style = {Width("33%"),HAlign('center')};

            grps2(1).ColSpecs = specs2;
            T_temp_par2.ColSpecGroups = grps2;  %Set the specifications on the Table
            add(R,T_temp_par2);


            add(R, space1);     %Add a Space

            % Variable names of Spacial parameters
            %Table 2 for Spacial parameters First Row
            tble2 = Table(5);            %Creates a table 1 with 5 columns for the page 1 (temporal parameters)
            tble2.Style = [tble2.Style app.tableStyle];        %Set the Style to the table
            tble2.ColSpecGroups = grps;     %Set the column widths to the table

            r1 = TableRow;     %Defines the first Table row
            r1 = TableInput(app,r1,'PARÁMETROS ESPACIALES',HeaderRowStyle,3,3);
            r1 = TableInput(app,r1,'VALORES NORMALES',HeaderRowStyle,2,3);
            append(tble2,r1);

            add(R, space2);     %Add a Space

            %Table 1 Second Rows
            r2=TableRow;     %Defines the first Table row
            r2 = TableInput(app,r2,' ',app.otherTextStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,1);
            append(tble2,r2);
            add(R,tble2);


            add(R, space1);   %Add a Space
            ERow1 = ["LONGITUD DE PASO (m)",string(round(Walking1.Lengths.("dseqRSTEP"),2)),string(round(Walking1.Lengths.("dseqLSTEP"),2)),append(string(round(app.N_Variables.Lengths.("dseqRSTEP.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dseqRSTEP.S"),2))),append(string(round(app.N_Variables.Lengths.("dseqLSTEP.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dseqLSTEP.S"),2)))];
            ERow2 = ["LONGITUD DE ZANCADA (m)",string(round(Walking1.Lengths.("dseqRSTRIDE"),2)),string(round(Walking1.Lengths.("dseqLSTRIDE"),2)),append(string(round(app.N_Variables.Lengths.("dseqRSTRIDE.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dseqRSTRIDE.S"),2))),append(string(round(app.N_Variables.Lengths.("dseqLSTRIDE.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dseqLSTRIDE.S"),2)))];
            ERow3 = ["ANCHO DE PASO (m)",string(round(Walking1.Lengths.("dmSTEPWIDTH"),2)),string(round(Walking1.Lengths.("dmSTEPWIDTH"),2)),append(string(round(app.N_Variables.Lengths.("dmRSTEPWIDTH.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dmRSTEPWIDTH.S"),2))),append(string(round(app.N_Variables.Lengths.("dmLSTEPWIDTH.M"),2)),' ± ',string(round(app.N_Variables.Lengths.("dmLSTEPWIDTH.S"),2)))];

            %Crates a table with all the temporal parameters
            T_esp_par= FormalTable([ERow1;ERow2;ERow3]);
            T_esp_par.ColSpecGroups= grps;
            T_esp_par.Style = OtherText;
            add(R,T_esp_par);

            %Creates a different table for the cadence since it only has 3 %columns
            ERow4 = ["VELOCIDAD PROMEDIO (m/s)",string(round(Walking1.Velocity.('velMEAN'),2)),append(string(round(app.N_Variables.Velocity.("velMEAN.M"),2)),' ± ',string(round(app.N_Variables.Velocity.("velMEAN.S"),2)))];
            T_esp_par2 =FormalTable(ERow4);
            T_esp_par2.Style = OtherText;
            T_esp_par2.ColSpecGroups = grps2;  %Set the specifications on the Table
            add(R,T_esp_par2);
            waitbar(0.5,h,"generando reporte 5");

            add(R, space1); %Add a Space
            note= Text(app.P_Info.Sessionnotes);
            note.Bold = true;
            note.FontFamilyName = 'Cambria';
            add(R,note);

            %Add a new page for indices and Deviation index
            app.PageNumber =app.PageNumber+1;
            R= PatientInfoTable(app,R);

            %Add a Space
            space3= Text(' ');
            space3.Style = {LineSpacing(3)};
            add(R, space3);

            %Add table for the Right and Left GPS and GDI Values
            tble3 = Table(3);
            tble3.Style = [tble3.Style app.tableStyle];
            tble3.Width = "70%";
            waitbar(0.6,h,"generando reporte 6");

            grps3(1) = TableColSpecGroup;    %Saves the variable for the col separation

            specs3(1) = TableColSpec;
            specs3(1).Style = {Width("50%"),HAlign('center'),Bold};
            specs3(2) = TableColSpec;
            specs3(2).Style = {Width("25%"),HAlign('center'),Color("green")};
            specs3(3) = TableColSpec;
            specs3(3).Style = {Width("25%"),HAlign('center'),Color("red")};

            grps3(1).ColSpecs = specs3;
            tble3.ColSpecGroups = grps3;     %Set the column widths to the table

            %Table 3 First row 3
            r1 = TableRow;          %Defines the first row
            r1 = TableInput(app,r1,'INDICES Y PUNTAJES DEL CICLO DE MARCHA',HeaderRowStyle,3,2);
            append(tble3,r1);

            %Table 3 Second row 3
            r2 = TableRow;
            r2 = TableInput(app,r2,' ',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,2);
            append(tble3,r2);
            add(R,tble3);

            %Variable names for GPS and GDI
            GRow1 = ["GAIT PROFILE SCORE (GPS)",string(round(Walking1.Scalar.("sRGPS"),1)),string(round(Walking1.Scalar.("sLGPS"),1))];
            GRow2 = ["GAIT DEVIATION INDEX (GDI)",string(round(Walking1.Scalar.("RGDI"),2)*100),string(round(Walking1.Scalar.("LGDI"),2)*100)];

            %Crates a table with all the indices
            %Creates a new Style for this table since it is not  as the ones of page 1
            GTStyle = {FontFamily('Cambria'),LineSpacing('1.1cm'),HAlign('center'),Width('70%'), Border('none'), ColSep('none'), RowSep('none')};
            G_ind= FormalTable([GRow1;GRow2]);
            G_ind.ColSpecGroups= grps3;
            G_ind.Style = GTStyle;
            add(R,G_ind);


            %Add table for the Gait Variable Score (GVS)
            tble4 = Table(3);
            tble4.Style = [tble4.Style app.tableStyle];
            tble4.Width = "70%";
            tble4.ColSpecGroups = grps3;

            add(R, space3);

            %Table 4 First row
            r1 = TableRow;          %Defines the first row
            r1 = TableInput(app,r1,'GAIT VARIABLE SCORE (GVS)',HeaderRowStyle,3,2);
            append(tble4,r1);

            %Table 4 Second row
            r2 = TableRow;
            r2 = TableInput(app,r2,'ÍNDICE DE DESVIACIÓN ARTICULAR (<=7)',HeaderRowStyle,3,2);
            append(tble4,r2);

            %Table 3 Second row 3
            r3 = TableRow;
            r3 = TableInput(app,r3,' ',FtClmStyle,1,2);
            r3 = TableInput(app,r3,'DERECHO',FtClmStyle,1,2);
            r3 = TableInput(app,r3,'IZQUIERDO',FtClmStyle,1,2);
            append(tble4,r3);
            add(R,tble4);
            waitbar(0.7,h,"generando reporte 7");
            %             %Variable names for GVS
            GVRow1 = ["ROTACIÓN PÉLVICA",string(Walking1.DAngles.("aGVS RPROT")),string(Walking1.DAngles.("aGVS LPROT"))];
            GVRow2 = ["OBLICUIDAD PÉLVICA",string(Walking1.DAngles.("aGVS RPOBLI")),string(Walking1.DAngles.("aGVS LPOBLI"))];
            GVRow3 = ["INCLINACIÓN PÉLVICA",string(Walking1.DAngles.("aGVS RPTILT")),string(Walking1.DAngles.("aGVS LPTILT"))];
            GVRow4 = ["FLEXO-EXTENSIÓN CADERA",string(Walking1.DAngles.("aGVS RHPFE")),string(Walking1.DAngles.("aGVS LHPFE"))];
            GVRow5 = ["ABD-ADD CADERA",string(Walking1.DAngles.("aGVS RHPAA")),string(Walking1.DAngles.("aGVS LHPAA"))];
            GVRow6 = ["ROTACIÓN INT-EXT CADERA",string(Walking1.DAngles.("aGVS RHPIE")),string(Walking1.DAngles.("aGVS LHPIE"))];
            GVRow7 = ["FLEXO-EXTENSIÓN RODILLA",string(Walking1.DAngles.("aGVS RKFE")),string(Walking1.DAngles.("aGVS LKFE"))];
            GVRow8 = ["FLEXO-EXTENSIÓN TOBILLO",string(Walking1.DAngles.("aGVS RAFE")),string(Walking1.DAngles.("aGVS LAFE"))];
            GVRow9 = ["PROGRESIÓN DEL PIE",string(Walking1.DAngles.("aGVS RAIE")),string(Walking1.DAngles.("aGVS LAIE"))];

            %Crates a table with all the indices
            GV_ind= FormalTable([GVRow1;GVRow2;GVRow3;GVRow4;GVRow5;GVRow6;GVRow7;GVRow8;GVRow9]);
            GV_ind.ColSpecGroups= grps3;
            GV_ind.Style = GTStyle;
            add(R,GV_ind);

            %Add a new page for Kinematic plots
            app.PageNumber =app.PageNumber+1;
            R= PatientInfoTable(app,R);

            %Load all the kinematic images into a struct called kmt_images
            for p=1:length(app.planos)
                ima=Image(append(app.ruta,'Images\',app.planos(p),'.png'));
                ima.Height = '860px';           %Set the height and width
                ima.Width = '220px';
                kmt_images.(app.planos(p)) = ima;
            end

            %Add the images into a table to plot them side by side
            lot = Table({kmt_images.Sagital, ' ', kmt_images.Frontal,' ', kmt_images.Transverse});
            lot.Style = {ResizeToFitContents(true)};
            %
            append(R,lot);

            if app.Kinet_individual== 1
                % Add  a page for kinetics plots
                app.PageNumber =app.PageNumber+1;
                R= PatientInfoTable(app,R);

                %Load all the kinetic images into a struct called knt_images
                for p=1:length(app.lines)
                    if p==4
                        ima=Image(append(app.ruta,'Images\',app.lines(p),'2.png'));
                    else
                        ima=Image(append(app.ruta,'Images\',app.lines(p),'.png'));
                    end

                    ima.Height = '165px';
                    ima.Width = '710px';
                    append(R,ima);
                end
            end

            if app.emg ==1
                % Add  a page for the EMG plots
                app.PageNumber =app.PageNumber+1;
                R= PatientInfoTable(app,R);

                add(R, space2);     %Add space

                Txt= Text('ELECTROMIOGRAFÍA');
                Txt.Style = [Txt.Style HeaderRowStyle];
                add(R, Txt);

                add(R, space1);     %Add space

                ima = Image(append(app.ruta,'Images\EMG_env.png'));

                ima.Height = '750px';
                ima.Width = '800px';
                append(R,ima);

                % Add  a page for the EMG-FFT plots
                app.PageNumber =app.PageNumber+1;
                R= PatientInfoTable(app,R);

                add(R, space2);     %Add space

                %Add a Space
                Txt= Text('ELECTROMIOGRAFÍA-ESPECTROS DE FRECUENCIA');
                Txt.Style = [Txt.Style HeaderRowStyle];
                add(R, Txt);

                add(R, space1);     %Add space

                ima = Image(append(app.ruta,'Images\FFT.png'));

                ima.Height = '750px';
                ima.Width = '800px';
                append(R,ima);
            end

            % Add  a page for the Muscle length plots
            app.PageNumber =app.PageNumber+1;
            R= PatientInfoTable(app,R);

            add(R, space2);     %Add space

            %Add a Space
            Txt= Text('CINEMÁTICA Y LONGITUDES MUSCULARES');
            Txt.Style = [Txt.Style HeaderRowStyle];
            add(R, Txt);

            add(R, space1);     %Add space

            ima = Image(append(app.ruta,'Images\M_Length.png'));
            ima2 = Image(append(app.ruta,'Images\M_Length2.png'));

            ima.Height = '350px';
            ima.Width = '700px';
            ima2.Height = '350px';
            ima2.Width = '700px';

            append(R,ima);
            append(R,ima2);

            % Add  a page for the Muscle length plots
            app.PageNumber =app.PageNumber+1;
            R= PatientInfoTable(app,R);

            add(R, space2);     %Add space

            %Add a Space
            Txt= Text('CINEMÁTICA Y VELOCIDADES MUSCULARES');
            Txt.Style = [Txt.Style HeaderRowStyle];
            add(R, Txt);

            add(R, space1);     %Add space

            ima = Image(append(app.ruta,'Images\M_Vel.png'));
            ima2 = Image(append(app.ruta,'Images\M_Vel2.png'));

            ima.Height = '350px';
            ima.Width = '700px';
            ima2.Height = '350px';
            ima2.Width = '700px';
            waitbar(0.8,h,"generando reporte 8");

            append(R,ima);
            append(R,ima2);

            close (R)
            close(h)


        end

        % Button pushed function: ReporTGenerCons
        function ReporTGenerConsButtonPushed(app, event)
            import mlreportgen.report.*                %Import the libraries
            import mlreportgen.dom.*     
            import mlreportgen.utils.*
           
            app.testype =6;     %Testype 6 is Consistency
            app.PageNumber =1;
            R = PatientInfoTable(app,0);           

            %Add a Space
            space2= Text(' ');
            space2.Style = {LineSpacing(2)};
            add(R, space2);    

            %Table Style
            HeaderRowStyle = {Bold,VAlign('middle'),HAlign('center'),FontFamily('Cambria')};        %Set the Style for the Main Header
            FtClmStyle = {Bold,VAlign('middle'),HAlign('center'),FontFamily('Cambria')};
            OtherText = {FontFamily('Cambria'),LineSpacing('1.1cm'),HAlign('center')};

            %TABLE HEADER
            tble1 = Table(5);            %Creates a table 1 with 5 columns for the page 1 (temporal parameters)
            tble1.Style = [tble1.Style app.tableStyle];        %Set the Style to the table
            
            grps(1) = TableColSpecGroup;    %Saves the variable for the col separation

            specs3(1) = TableColSpec;
            specs3(1).Style = {Width("34%"),HAlign('center'),Bold};    %Set the first column to be wider than the others
            specs3(2) = TableColSpec;
            specs3(2).Style = {Width("16%"),HAlign('center'),Color("green")};
            specs3(3) = TableColSpec;
            specs3(3).Style = {Width("16%"),HAlign('center'),Color("red")};
            specs3(4) = TableColSpec;
            specs3(4).Style = {Width("16%"),HAlign('center')};
            specs3(5) = TableColSpec;
            specs3(5).Style = {Width("16%"),HAlign('center')};

            grps(1).ColSpecs = specs3;
            tble1.ColSpecGroups = grps;     %Set the column widths to the table

            %Table 1 for Temporal parameters First Row
            r1 =TableRow;     %Defines the first Table row  
            % TableInput(app,row,tx,Styl,col,lspac)
            %row = Table row to be filled
            % tx = Text to be written
            % Styl= Text style
            % col = Columns to be merged
            %lspac = Linespacing
            r1 = TableInput(app,r1,'PARÁMETROS TEMPORALES',HeaderRowStyle,3,2);            
            r1 = TableInput(app,r1,'VALORES NORMALES',HeaderRowStyle,2,2);
            append(tble1,r1);      

            %Table 1 Second Rows
            r2=TableRow;     %Defines the first Table row
            r2 = TableInput(app,r2,' ',app.otherTextStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,2);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,2);
            append(tble1,r2);
            add(R,tble1);

            %Add a Space
            space1= Text(' ');
            space1.Style = {LineSpacing(1)};
            add(R, space1); 

            % Variable names of Temporal parameters    
%             Temp = ["STANCE","SWING","DBLSTANCE","STANCE","SWING","STRIDE"];
            Temp = ["STANCE","SWING","DBLSTANCE","STANCE","SWING","STRIDE"];    
            NTemp= ["FASE DE APOYO (%)","FASE DE VUELO (%)","DOBLE APOYO (%)","DURACIÓN APOYO (s)","DURACIÓN BALANCEO (s)","TIEMPO DE ZANCADA (s)"];

            for i = 1:6
                r=Temp(i);                
                if i ==1 || i==2 || i==3
                    X = [app.P_Variables.(app.trial(1)).Scalar.(append("sR",r)),app.P_Variables.(app.trial(2)).Scalar.(append("sR",r)),app.P_Variables.(app.trial(3)).Scalar.(append("sR",r))];
                    Y = [app.P_Variables.(app.trial(1)).Scalar.(append("sL",r)),app.P_Variables.(app.trial(2)).Scalar.(append("sL",r)),app.P_Variables.(app.trial(3)).Scalar.(append("sL",r))];
                    TRow.(r) = [NTemp(i),append(string(round(mean(X)*100,1))," ± ",string(round(std(X)*100,1))),append(string(round(mean(Y)*100,1))," ± ",string(round(std(Y)*100,1))),append(string(round(app.N_Variables.Scalar.(append("sR",r,".M")),3)*100),' ± ',string(round(app.N_Variables.Scalar.(append("sR",r,".S")),3)*100)),append(string(round(app.N_Variables.Scalar.(append("sL",r,".M")),3)*100),' ± ',string(round(app.N_Variables.Scalar.(append("sL",r,".S")),3)*100))];

                else 
                    X = [app.P_Variables.(app.trial(1)).Times.(append("tseqR",r)),app.P_Variables.(app.trial(2)).Times.(append("tseqR",r)),app.P_Variables.(app.trial(3)).Times.(append("tseqR",r))];
                    Y = [app.P_Variables.(app.trial(1)).Times.(append("tseqL",r)),app.P_Variables.(app.trial(2)).Times.(append("tseqL",r)),app.P_Variables.(app.trial(3)).Times.(append("tseqL",r))];
                    TRow.(append("t",r)) = [NTemp(i),append(string(round(mean(X),2))," ± ",string(round(std(X),2))),append(string(round(mean(Y),2))," ± ",string(round(std(Y),2))),append(string(round(app.N_Variables.Times.(append("tseqR",r,".M")),2)),' ± ',string(round(app.N_Variables.Times.(append("tseqR",r,".S")),2))),append(string(round(app.N_Variables.Times.(append("tseqL",r,".M")),2)),' ± ',string(round(app.N_Variables.Times.(append("tseqL",r,".S")),2)))];
                end
            end

            %Crates a table with all the temporal parameters
%             T_esp_par= FormalTable([TRow1;TRow2;TRow3;TRow4;TRow5;TRow6]);
            T_esp_par= FormalTable([TRow.STANCE;TRow.SWING;TRow.DBLSTANCE;TRow.tSTANCE;TRow.tSWING;TRow.tSTRIDE]);
            T_esp_par.ColSpecGroups= grps;
            T_esp_par.Style = OtherText;
            add(R,T_esp_par);

            %Creates a different table for the cadence since it only has 3 %columns
            X = [app.P_Variables.(app.trial(1)).Frequencies.("fqCADENCE"),app.P_Variables.(app.trial(2)).Frequencies.("fqCADENCE"),app.P_Variables.(app.trial(3)).Frequencies.("fqCADENCE")];
            TRow7 = ["CADENCIA (pasos/min)",append(string(round(mean(X)*60,1))," ± ",string(round(std(X)*60,1))),append(string(round(app.N_Variables.Frequencies.("fqCADENCE.M"),3)*60),' ± ',string(round(app.N_Variables.Frequencies.("fqCADENCE.S"),3)*60))];
%             TRow7 = ["CADENCIA (pasos/min)",append(string(cell2mat(app.P_Variables.Cadence3.("fqCADENCE.M")))," ± ",string(cell2mat(app.P_Variables.Cadence3.("fqCADENCE.S")))),append(string(round(cell2mat(app.N_Variables.Cadence.("Cadence.M")),2)),' + ',string(round(cell2mat(app.N_Variables.Cadence.("Cadence.S")),2)))];
            T_temp_par2 =FormalTable(TRow7);
            T_temp_par2.Style = OtherText;

            %Set the style for the cadence table
            grps2(1) = TableColSpecGroup;
            %Specifications for each column for the Cadence Table
            specs2(1) = TableColSpec;
            specs2(1).Style = {Width("34%"),HAlign('center'),Bold};
            specs2(2) = TableColSpec;
            specs2(2).Style = {Width("33%"),HAlign('center')};
            specs2(3) = TableColSpec;
            specs2(3).Style = {Width("33%"),HAlign('center')};

            grps2(1).ColSpecs = specs2;
            T_temp_par2.ColSpecGroups = grps2;  %Set the specifications on the Table
            add(R,T_temp_par2);
           
            add(R, space1);     %Add a Space

            % Variable names of Spacial parameters 
            %Table 2 for Spacial parameters First Row
            tble2 = Table(5);            %Creates a table 1 with 5 columns for the page 1 (temporal parameters)
            tble2.Style = [tble2.Style app.tableStyle];        %Set the Style to the table
            tble2.ColSpecGroups = grps;     %Set the column widths to the table

            r1 = TableRow;     %Defines the first Table row  
            r1 = TableInput(app,r1,'PARÁMETROS ESPACIALES',HeaderRowStyle,3,3);            
            r1 = TableInput(app,r1,'VALORES NORMALES',HeaderRowStyle,2,3);
            append(tble2,r1);           

            add(R, space2);     %Add a Space

            %Table 1 Second Rows
            r2=TableRow;     %Defines the first Table row
            r2 = TableInput(app,r2,' ',app.otherTextStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'DERECHO',FtClmStyle,1,1);
            r2 = TableInput(app,r2,'IZQUIERDO',FtClmStyle,1,1);
            append(tble2,r2);
            add(R,tble2);

            Esp = ["STEP","STRIDE","STEPWIDTH"];
            NEsp= ["LONGITUD DE PASO (m)","LONGITUD DE ZANCADA (M)","ANCHO DE PASO (m)"];

            for i = 1:3
                r=Esp(i);
                if i ==1 || i==2
                    X = [app.P_Variables.(app.trial(1)).Lengths.(append("dseqR",r)),app.P_Variables.(app.trial(2)).Lengths.(append("dseqL",r)),app.P_Variables.(app.trial(3)).Lengths.(append("dseqL",r))];
                    Y = [app.P_Variables.(app.trial(1)).Lengths.(append("dseqL",r)),app.P_Variables.(app.trial(2)).Lengths.(append("dseqL",r)),app.P_Variables.(app.trial(3)).Lengths.(append("dseqL",r))];
                    ERow.(r) = [NEsp(i),append(string(round(mean(X),1))," ± ",string(round(std(X),1))),append(string(round(mean(Y),1))," ± ",string(round(std(Y),1))),append(string(round(app.N_Variables.Lengths.(append("dseqR",r,".M")),3)),' ± ',string(round(app.N_Variables.Lengths.(append("dseqR",r,".S")),3))),append(string(round(app.N_Variables.Lengths.(append("dseqL",r,".M")),3)),' ± ',string(round(app.N_Variables.Lengths.(append("dseqL",r,".S")),3)))];
                elseif i ==3
                    X = [app.P_Variables.(app.trial(1)).Lengths.(append("dm",r)),app.P_Variables.(app.trial(2)).Lengths.(append("dm",r)),app.P_Variables.(app.trial(3)).Lengths.(append("dm",r))];
                    ERow.(r) = [NEsp(i),append(string(round(mean(X),1))," ± ",string(round(std(X),1))),append(string(round(mean(X),1))," ± ",string(round(std(X),1))),append(string(round(app.N_Variables.Lengths.(append("dmR",r,".M")),2)),' ± ',string(round(app.N_Variables.Lengths.(append("dmR",r,".S")),2))),append(string(round(app.N_Variables.Lengths.(append("dmL",r,".M")),2)),' ± ',string(round(app.N_Variables.Lengths.(append("dmL",r,".S")),2)))];               
                end
            end

            add(R, space1);   %Add a Space

            %Crates a table with all the temporal parameters
            T_esp_par= FormalTable([ERow.STEP;ERow.STRIDE;ERow.STEPWIDTH]);
            T_esp_par.ColSpecGroups= grps;
            T_esp_par.Style = OtherText;
            add(R,T_esp_par);

%             vlo = app.N_Variables.Velocity
%             vlo1 = app.N_Variables.Velocity.("velMEAN.S")

%           Creates a different table for the cadence since it only has 3 %columns
            X = [app.P_Variables.(app.trial(1)).Velocity.("velMEAN"),app.P_Variables.(app.trial(2)).Velocity.("velMEAN"),app.P_Variables.(app.trial(3)).Velocity.("velMEAN")];
            ERow4 = ["VELOCIDAD PROMEDIO (m/s)",append(string(round(mean(X),1))," ± ",string(round(std(X),1))),append(string(round(app.N_Variables.Velocity.("velMEAN.M"),2)),' ± ',string(round(app.N_Variables.Velocity.("velMEAN.S"),2)))];
            T_esp_par2 =FormalTable(ERow4);
            T_esp_par2.Style = OtherText;
            T_esp_par2.ColSpecGroups = grps2;  %Set the specifications on the Table
            add(R,T_esp_par2);

            Pnum = 1;

            Lat=["DERECHA","IZQUIERDA","DER. VS. IZQ."];

            %Load all the kinematic images into a struct called kmt_images
            for pg =1:3
                for p=1:length(app.planos)
                    ima=Image(append(app.ruta,'Images\',app.planos(p),num2str(pg),'.png'));
                    ima.Height = '823px';           %Set the height and width
                    ima.Width = '220px';
                    kmt_images.(app.planos(p)) = ima;                
                end  

                %Add a new page for Kinematic plots
                app.PageNumber =Pnum+pg;
                R= PatientInfoTable(app,R);
                Txt= Text(append("CINEMÁTICA ",Lat(pg)));
                Txt.Style = [Txt.Style HeaderRowStyle];
                add(R, Txt);

                %Add the images into a table to plot them side by side
                lot = Table({kmt_images.Sagital, ' ', kmt_images.Frontal,' ', kmt_images.Transverse});
                lot.Style = {ResizeToFitContents(true)};

                append(R,lot);
            end

            %Load all the kinematic images into a struct called kmt_images
            if app.kinet ==3
                Pnum=5;
                for pg =1:3 %To plot the 3 kinetic graphs onf the consistency test
                    %Add a new page for Kinematic plots
                    app.PageNumber =Pnum+pg;
                    R= PatientInfoTable(app,R);
                    Txt= Text(append("CINÉTICA ",Lat(pg)));
                    Txt.Style = [Txt.Style HeaderRowStyle];
                    add(R, Txt);

                    for p=1:5 % To add each line of the kinetic page
                        ima=Image(append(app.ruta,'Images\',num2str(p),num2str(pg),'.png'));
                        ima.Height = '160px';           %Set the height and width
                        ima.Width = '710px';
                        append(R,ima)
                    end
                end
            end
            
            
            close (R)

        end

        % Button pushed function: TerminarButton
        function TerminarButtonPushed(app, event)
           app.trial=[];
           app.Kinet_individual=0;
           app.kinet=0;
           app.Walking = struct();
           close all;           
           clc;
           delete(gcf)
           app.trial=[];
           app.GrficasdeCinticaCheckBox.Value=0;
           app.GrficasdeElectromiografiaCheckBox.Value=0;
           app.GrficasdecinemticaCheckBox.Value=0;
           app.GrficasdelongitudesmuscularesCheckBox.Value=0;
           app.GrficastestdeconsistenciacinemticaCheckBox.Value=0;
           app.GrficastestdeconsistenciacinticaCheckBox.Value=0; 
           app.GrficasdeCinticaCheckBox.FontColor=[0,0,0];
           app.GrficasdeElectromiografiaCheckBox.FontColor=[0,0,0];
           app.GrficasdecinemticaCheckBox.FontColor=[0,0,0];
           app.GrficasdelongitudesmuscularesCheckBox.FontColor=[0,0,0];
           app.GrficastestdeconsistenciacinemticaCheckBox.FontColor=[0,0,0];
           app.GrficastestdeconsistenciacinticaCheckBox.FontColor=[0,0,0];
           app.CONTINUARButton.Visible="off";
           app.TabGroup.SelectedTab=app.MainTab;
    
            
        end

        % Button pushed function: CONTINUARButton
        function CONTINUARButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.PlotreportTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIMainWindow = uifigure('Visible', 'off');
            app.UIMainWindow.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIMainWindow);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.REPORTEGRFICASC = uibutton(app.MainTab, 'push');
            app.REPORTEGRFICASC.ButtonPushedFcn = createCallbackFcn(app, @REPORTEGRFICASCPushed, true);
            app.REPORTEGRFICASC.Text = {'REPORTE GRÁFICAS '; 'CINEMÁTICA Y CINÉTICA'};
            app.TomaseleccionadaEditFieldLabel = uilabel(app.MainTab);
            app.TomaseleccionadaEditFieldLabel.Text = 'Toma seleccionada';
            app.TomaseleccionadaEditField = uieditfield(app.MainTab, 'text');
            app.SesionEditFieldLabel = uilabel(app.MainTab);
            app.SesionEditFieldLabel.Text = 'Sesion';
            app.SesionEditField = uieditfield(app.MainTab, 'text');
            app.NormativadeadultoCheckBox = uicheckbox(app.MainTab);
            app.NormativadeadultoCheckBox.Text = 'Normativa de adulto';
            app.GrficasdecinemticaCheckBox = uicheckbox(app.MainTab);
            app.GrficasdecinemticaCheckBox.Text = 'Gráficas de cinemática';
            app.GrficasdeCinticaCheckBox = uicheckbox(app.MainTab);
            app.GrficasdeCinticaCheckBox.Text = 'Gráficas de Cinética';
            app.GrficasdeElectromiografiaCheckBox = uicheckbox(app.MainTab);
            app.GrficasdeElectromiografiaCheckBox.Text = 'Gráficas de Electromiografia';
            app.GrficasdelongitudesmuscularesCheckBox = uicheckbox(app.MainTab);
            app.GrficasdelongitudesmuscularesCheckBox.Text = 'Gráficas de longitudes musculares';
            app.GrficastestdeconsistenciacinemticaCheckBox = uicheckbox(app.MainTab);
            app.GrficastestdeconsistenciacinemticaCheckBox.Text = 'Gráficas test de consistencia cinemática';
            app.GrficastestdeconsistenciacinticaCheckBox = uicheckbox(app.MainTab);
            app.GrficastestdeconsistenciacinticaCheckBox.Text = 'Gráficas test de consistencia cinética';
            app.CONTINUARButton = uibutton(app.MainTab, 'push');
            app.CONTINUARButton.ButtonPushedFcn = createCallbackFcn(app, @CONTINUARButtonPushed, true);
            app.CONTINUARButton.Visible = 'off';
            app.CONTINUARButton.Text = 'CONTINUAR';
            app.ERRORESLabel = uilabel(app.MainTab);
            app.ERRORESLabel.Visible = 'off';
            app.ERRORESLabel.Text = 'ERRORES';
            app.PlotreportTab = uitab(app.TabGroup);
            app.PlotreportTab.Title = 'Plot report';
            app.ReporTGenerGraphs = uibutton(app.PlotreportTab, 'push');
            app.ReporTGenerGraphs.ButtonPushedFcn = createCallbackFcn(app, @ReporTGenerGraphsButtonPushed, true);
            app.ReporTGenerGraphs.Text = 'Generar reporte individual';
            app.ReporTGenerCons = uibutton(app.PlotreportTab, 'push');
            app.ReporTGenerCons.ButtonPushedFcn = createCallbackFcn(app, @ReporTGenerConsButtonPushed, true);
            app.ReporTGenerCons.Text = {'Generar reporte'; ' Test de Consistencia'};
            app.TerminarButton = uibutton(app.PlotreportTab, 'push');
            app.TerminarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarButtonPushed, true);
            app.TerminarButton.Text = 'Terminar';
            % Show the figure after all components are created
            app.UIMainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CodigoGraficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIMainWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIMainWindow)
        end
    end
end